classdef oligoprimeron_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COSPLAY                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AddtailstohomologousprimersTab  matlab.ui.container.Tab
        GeneratetailedprimersButton_2   matlab.ui.control.Button
        ModulepositionSpinner_2Label    matlab.ui.control.Label
        ModulepositionSpinner_2         matlab.ui.control.Spinner
        Forwardhomologoussequence53Label  matlab.ui.control.Label
        Forwardhomologoussequence53EditField  matlab.ui.control.EditField
        Reversehomologoussequence53Label  matlab.ui.control.Label
        Reversehomologoussequence53EditField  matlab.ui.control.EditField
        TextArea_2                      matlab.ui.control.TextArea
        Tailedprimers53Label            matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        CopyforwardprimerButton         matlab.ui.control.Button
        CopyreverseprimerButton         matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        CopiedLabel                     matlab.ui.control.Label
        CopiedLabel_2                   matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        Fusedtomodule4Label_2           matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        PUC57BsaIhomologyLabel_2        matlab.ui.control.Label
        homologyBsaIPUC57Label_2        matlab.ui.control.Label
        tailedprimer3Label_2            matlab.ui.control.Label
        tailedprimer5Label_2            matlab.ui.control.Label
        moduleLabel_4                   matlab.ui.control.Label
        homologyLabel_4                 matlab.ui.control.Label
        homologyLabel_5                 matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        Label_39                        matlab.ui.control.Label
        DesignentireprimersTab          matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        Modulesequence53Label           matlab.ui.control.Label
        ModulepositionSpinnerLabel      matlab.ui.control.Label
        ModulepositionSpinner           matlab.ui.control.Spinner
        GeneratetailedprimersButton     matlab.ui.control.Button
        OptionsButton                   matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        SingleintegrationLabel          matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        Fusedtomodule4Label             matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        Tailedprimers53Label_2          matlab.ui.control.Label
        ClearButton_2                   matlab.ui.control.Button
        CopyforwardprimerButton_2       matlab.ui.control.Button
        CopyreverseprimerButton_2       matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        CopiedLabel_3                   matlab.ui.control.Label
        CopiedLabel_4                   matlab.ui.control.Label
        WaitplsLabel                    matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        PUC57BsaIhomologyLabel          matlab.ui.control.Label
        homologyBsaIPUC57Label          matlab.ui.control.Label
        tailedprimer3Label              matlab.ui.control.Label
        tailedprimer5Label              matlab.ui.control.Label
        moduleLabel_3                   matlab.ui.control.Label
        Label_40                        matlab.ui.control.Label
        Label_41                        matlab.ui.control.Label
        ConceptsTab                     matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        BsaIisusedtogeneratespecificLabel  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_2  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_3  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_4  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_5  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_6  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_7  matlab.ui.control.Label
        Button_5                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Label_42                        matlab.ui.control.Label
        Label_43                        matlab.ui.control.Label
        Label_44                        matlab.ui.control.Label
        Label_45                        matlab.ui.control.Label
        Label_46                        matlab.ui.control.Label
        Label_47                        matlab.ui.control.Label
        Label_48                        matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_69  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_70  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_71  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_72  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_74  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_75  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_76  matlab.ui.control.Label
        ModularplasmidassemblyLabel     matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_77  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_78  matlab.ui.control.Label
        BsaIisusedtogeneratespecificLabel_79  matlab.ui.control.Label
        GCGTLabel_2                     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        GGTCTCLabel                     matlab.ui.control.Label
        LefttailLabel                   matlab.ui.control.Label
        RighttailLabel                  matlab.ui.control.Label
        ModuleLabel                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        vLabel                          matlab.ui.control.Label
        BsaIposLabel                    matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        Panel_4                         matlab.ui.container.Panel
        GGTCTCLabel_2                   matlab.ui.control.Label
        GGLabel                         matlab.ui.control.Label
        GTLabel                         matlab.ui.control.Label
        TCLabel                         matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        ALabel_2                        matlab.ui.control.Label
        ALabel_3                        matlab.ui.control.Label
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        AGLabel                         matlab.ui.control.Label
        GALabel                         matlab.ui.control.Label
        AGLabel_2                       matlab.ui.control.Label
        TLabel                          matlab.ui.control.Label
        GLabel                          matlab.ui.control.Label
        ALabel_4                        matlab.ui.control.Label
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CCAGAGLabel                     matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        ILabel_19                       matlab.ui.control.Label
        ILabel_20                       matlab.ui.control.Label
        ILabel_21                       matlab.ui.control.Label
        ILabel_22                       matlab.ui.control.Label
        ILabel_23                       matlab.ui.control.Label
        ILabel_24                       matlab.ui.control.Label
        ILabel_25                       matlab.ui.control.Label
        ILabel_26                       matlab.ui.control.Label
        ILabel_27                       matlab.ui.control.Label
        ILabel_28                       matlab.ui.control.Label
        ILabel_29                       matlab.ui.control.Label
        ILabel_30                       matlab.ui.control.Label
        ILabel_31                       matlab.ui.control.Label
        ILabel_32                       matlab.ui.control.Label
        ILabel_33                       matlab.ui.control.Label
        ILabel_34                       matlab.ui.control.Label
        ILabel_35                       matlab.ui.control.Label
        ILabel_36                       matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        SelectasilentpointLabel         matlab.ui.control.Label
        mutationinthecorrectLabel       matlab.ui.control.Label
        readingframeLabel               matlab.ui.control.Label
        SilentpointmutationsLabel       matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        BsaILabel                       matlab.ui.control.Label
        SomesilentmutationsLabel        matlab.ui.control.Label
        arenotavailablebecauseLabel     matlab.ui.control.Label
        theBsaIsiteoverlaptheLabel      matlab.ui.control.Label
        linkersitepleaseselectLabel     matlab.ui.control.Label
        anothermutationorLabel          matlab.ui.control.Label
        manuallyalterthemoduleLabel     matlab.ui.control.Label
        sequenceLabel                   matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        GAGACCLabel                     matlab.ui.control.Label
        GALabel_2                       matlab.ui.control.Label
        AGLabel_3                       matlab.ui.control.Label
        GALabel_3                       matlab.ui.control.Label
        ALabel_5                        matlab.ui.control.Label
        GLabel_4                        matlab.ui.control.Label
        TLabel_2                        matlab.ui.control.Label
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CTLabel                         matlab.ui.control.Label
        TGLabel                         matlab.ui.control.Label
        GGLabel_2                       matlab.ui.control.Label
        ALabel_6                        matlab.ui.control.Label
        ALabel_7                        matlab.ui.control.Label
        ALabel_8                        matlab.ui.control.Label
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CTCTGGLabel                     matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Label_35                        matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        ILabel                          matlab.ui.control.Label
        ILabel_2                        matlab.ui.control.Label
        ILabel_3                        matlab.ui.control.Label
        ILabel_4                        matlab.ui.control.Label
        ILabel_5                        matlab.ui.control.Label
        ILabel_6                        matlab.ui.control.Label
        ILabel_7                        matlab.ui.control.Label
        ILabel_8                        matlab.ui.control.Label
        ILabel_9                        matlab.ui.control.Label
        ILabel_10                       matlab.ui.control.Label
        ILabel_11                       matlab.ui.control.Label
        ILabel_12                       matlab.ui.control.Label
        ILabel_13                       matlab.ui.control.Label
        ILabel_14                       matlab.ui.control.Label
        ILabel_15                       matlab.ui.control.Label
        ILabel_16                       matlab.ui.control.Label
        ILabel_17                       matlab.ui.control.Label
        ILabel_18                       matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        SomesilentmutationsLabel_2      matlab.ui.control.Label
        arenotavailablebecauseLabel_2   matlab.ui.control.Label
        themutationsshouldbeatLabel     matlab.ui.control.Label
        least5ntawayfromthe3Label       matlab.ui.control.Label
        endpleaseselectanotherLabel     matlab.ui.control.Label
        mutationorextendtheLabel        matlab.ui.control.Label
        homologoussequenceLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        left_sites={'ATCC' 'TATG' 'GAAC' 'CGCA' 'CAGG' 'AGCA'};
        right_sites={'CATA' 'GTTC' 'TGCG' 'CCTG' 'TGCT' 'ATGG'};
        left_puc='CGAATGCATCTAGATATCGGATCCCGGTCTCG';
        right_puc='GCAGGCCTCTGCAGTCGACGGGCCCGGTCTCT';
        bsaI={'GGTCTC' 'GAGACC'};
        forwardp=[];
        reversep=[];
        forwardp2=[];
        reversep2=[];
        concepts = 1;
        myTimer
        myTimer2
        myTimer3
        myTimer4
        dna='AGCT'; %a=1 g=2 c=3 t=4
        rlnCt=1.987*log(0.5/1000000); %0.5 oligos µM
        h=[-7.9 -7.8 -8.4 -7.2 ; -8.2 -8 -9.8 -8.4 ; -8.5 -10.6 -8 -7.8 ; -7.2 -8.5 -8.2 -7.9]*1000; % enthalpie
        s=[-22.2 -21 -22.4 -20.4 ; -22.2 -19.9 -24.4 -22.4 ; -22.7 -27.2 -19.9 -21 ; -21.3 -22.7 -22.2 -22.2]; %entropie
        inih=[2.3 0.1 0.1 2.3]*1000; %enthalpie debut
        inis=[4.1 -2.8 -2.8 4.1]; %entrop debut
        hcorr = [26.9 21.1 15.7 10.8 16 12.2; 8.8 16 12.2 25.9 15.5 17.2]*1000;
        scorr = [71.1 55 38.8 25 41.6 29.2; 19 37.7 27.8 68 42.7 44.4];
        lhcorec = [10.6000    6.0000   10.0000    6.0000   10.0000    6.0000; 5.6000   10.4000    6.2000   10.4000    6.2000    8.0000 ]*1000 ;
        lscorec = [29.3000   15.3000   27.9000   15.3000   27.9000   15.3000; 14.1000   29.1000   15.5000   29.1000   15.5000   19.9000 ] ;
        rhcorec =[8.0000    6.2000   10.4000    6.2000   10.4000    5.6000; 6.0000   10.0000    6.0000   10.0000    6.0000   10.6000]*1000 ;
        rscorec = [19.9000   15.5000   29.1000   15.5000   29.1000   14.1000; 15.3000   27.9000   15.3000   27.9000   15.3000   29.3000] ;
        gccorr = [0 -1 0 0 0 0; 0 0 0 0 -1 0];
        symm=-1.4;
        q5=1.6;%-3.21; q5 correction
        kac=0.12; % monvalent cation mM
        identite = 1; %seq align
        mismatch = -1; %seq align
        gap = -2; %seq
        Tailleopt = 20;
        Tailleopto = 20;
        Taillemin = 11;
        Taillemino = 11;
        Taillemax = 40;
        Taillemaxo = 40;
        Tailleweight =1;
        Tailleweighto = 1;
        Taillep = 0.2; %penalty=Taillep*abs(y - Tailleopt)*Taillweight
        Tmopt = 67;
        Tmopto = 67;
        Tmweight = 1;
        Tmweighto = 1;
        Tmp = 1/16; %penalty=Tmp*(y - Tmopt)*(y - Tmopt)*Tmweight
        StrIImin = 8;
        StrIImino = 8;
        StrIIweight = 1;
        StrIIweighto = 1; %penalty=max(0,(y-StrIImin))*StrIIweight
        StrII3min = 3;
        StrII3mino = 3;
        StrII3weight = 1;
        StrII3weighto = 1; %penalty=max(0,(y-StrII3min))*StrII3weight
        StrIIMatmin = 11;
        StrIIMatmino = 11;
        StrIIMatweight = 1;
        StrIIMatweighto = 1; %penalty=max(0,(y-StrIIMatmin))*StrIIMatweight
        StrII3Matmin = 7;
        StrII3Matmino = 7;
        StrII3Matweight = 1;
        StrII3Matweighto = 1; %penalty=max(0,(y-StrII3Matmin))*StrII3Matweight
        Repeatsmin = 3;
        positionchanw = [1 2 3 2 3 4];
        modchangew1 = 0;
        modchangew2 = 0;
        Repeatsmino = 3;
        Repeatsweight = 1;
        Repeatsweighto = 1; %penalty=max(0,(y-Repeatsmin))*Repeatsweight
        GCweight = 1;
        GCweighto = 1; %penalty=max(0,max((30-GCratio*100)/5,(GCratio*100-70)/5))*GCweight
        GC3weight = 1;
        GC3weighto = 1; %penalty=max(2-GC3ratio*5,GC3ratio*5-3)*GC3weight
        deltaTmweight = 1;
        deltaTmweighto = 1; %penalty=abs(T1-T2)*deltaTmweight
        modchangew3 = 'Y';
        Stability3weight =1;
        Stability3weighto =1; %penalty n=1:T>0 n=2:T>10 n*Stability3weight
        modul = {};
        logg2;
        optionsbox
        ascIfseI = 'GGCGCGCCGGCCGGCC';
        ascIfseIo = 'GGCGCGCCGGCCGGCC';
 % Description
    end

    methods (Access = private)
    
        
        function results = bsaIfind(app,seq)
            l=length(seq);
            out1={[],[]};
           if l>5
           for n=[1 2]   
           for i=1:l-5
               k=1;
               while (k<=6)&&(seq(i+k-1)==app.bsaI{n}(k))
                  k=k+1;
               end
               if k==7
                  if n==1
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  else
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  end
               end
           end
           end
           end
           results=out1;
        end
        
        function copchang4(app)
           app.CopiedLabel_4.Visible = 'off'; 
        end
        
        function copchang3(app)
           app.CopiedLabel_3.Visible = 'off'; 
        end
        
        function copchang2(app)
           app.CopiedLabel_2.Visible = 'off'; 
        end
        
        function copchang(app)
           app.CopiedLabel.Visible = 'off'; 
        end
        
        
        function [result,resul2] = seq2num(app,seq)
           numseq=[];
           nba=0;
           nbg=0;
          nbc=0;
           nbt=0;
           for i=1:length(seq) 
               if seq(i)=='A'
                   numseq=[numseq 1];
                   nba=nba+1;
               else
                   if seq(i)=='G'
                       numseq=[numseq 2];
                       nbg=nbg+1;
                   else
                       if seq(i)=='C'
                           numseq=[numseq 3];
                           nbc=nbc+1;
                       else
                           if seq(i)=='T'
                           numseq=[numseq 4];
                           nbt=nbt+1;
                           end
                       end
                   end
               end
           end
           gc=(nbg+nbc)/(nba+nbt+nbg+nbc);
           result=numseq;
           resul2=gc;
        end
        
        function result = num2seq(app,seq)
            seqnum=[];
            for i=1:length(seq) seqnum=[seqnum app.dna(seq(i))];
            end
            result=seqnum;
        end
        
        function [result,gegg] = tmf(app,seq,hmodif,smodif,gcmodif)
            [seqn, fgc] = seq2num(app,seq);
            n=length(seqn);
            fgc = (fgc*n+gcmodif)/n;
            somh=app.inih(seqn(1))+app.inih(seqn(end));
            soms=app.inis(seqn(1))+app.inis(seqn(end));
            for i=1:n-1
                somh=somh+app.h(seqn(i),seqn(i+1));
                soms=soms+app.s(seqn(i),seqn(i+1));                
            end
            if mod(n,2)==0
%                 l=n/2-0.5;
%             else
                l=n/2;
            flpseq=fliplr(seqn(end-l+1:end));
            flpseq=5-flpseq;
            if isequal(seqn(1:l),flpseq)
                soms=soms+app.symm;
            end
            end            
            tmoligos=(somh+hmodif)/(soms+smodif+app.rlnCt);
            tmoligos=1/(1/tmoligos+((4*fgc-3.95)*10^-5*log(app.kac)+9.4*10^-6*(log(app.kac))^2))-273.15+app.q5;
            result=tmoligos;
            gegg = fgc;
        end
        
        function resul = seqcomp(app,seq1)
            for i=1:length(seq1)
                if seq1(i)=='A'
                     seq1(i) = 'T';
                else
                    if seq1(i)=='G'
                         seq1(i)='C';
                    else
                        if seq1(i)=='C'
                             seq1(i)='G';
                        else
                             seq1(i)='A';
                        end
                    end
                end
            end
            resul=seq1;
        end
        
        function resul = seqidentity(app,seq1,seq2,v1,v2) %seq2 can be analysed
           lseq1 = length(seq1);
           lseq2 = length(seq2);
           seq1=fliplr(seq1);
           seq1=seqcomp(app,seq1);
           optgc = zeros(lseq1, lseq2);
           mse1 = zeros(1,lseq2);
           if ~isempty(v1)
               for i=1:length(v1)
                  optgc(v1(i),v2(i))=-Inf;
               end
           end
           if (lseq1>2)&&(lseq2>2)
               for j=1:lseq2
                   if seq2(j)==seq1(1)
                       if optgc(1,j)>-Inf
                       optgc(1,j)=app.identite;
                       end
                   else
                       if optgc(1,j)>-Inf
                       optgc(1,j)=app.mismatch;
                       end
                   end
               end 
               for j=2:lseq1
                   if seq1(j)==seq2(1)
                       if optgc(j,1)>-Inf
                       optgc(j,1)=app.identite;
                       end
                   else
                       if optgc(j,1)>-Inf
                       optgc(j,1)=app.mismatch;
                       end
                   end
               end                
               for j=2:lseq2
                   if seq2(j)==seq1(2)
                       if optgc(2,j)>-Inf
                       optgc(2,j)=app.identite;
                       end
                   else
                       if optgc(2,j)>-Inf
                       optgc(2,j)=app.mismatch;
                       end
                   end
                   if optgc(2-1,j-1)>0
                      optgc(2,j)=optgc(2,j)+optgc(2-1,j-1);
                   end
               end 
               for j=3:lseq1
                   if seq1(j)==seq2(2)
                       if optgc(j,2)>-Inf
                       optgc(j,2)=app.identite;
                       end
                   else
                       if optgc(j,2)>-Inf
                       optgc(j,2)=app.mismatch;
                       end
                   end
                   if optgc(j-1,2-1)>0
                      optgc(j,2)=optgc(j,2)+optgc(j-1,2-1);
                   end
               end
               for i=3:min(lseq2,lseq1)
                   for j=i:(lseq2)
                      if seq2(j)==seq1(i)
                      if optgc(i,j)>-Inf
                      optgc(i,j)=app.identite;
                      end
                      else
                      if optgc(i,j)>-Inf
                      optgc(i,j)=app.mismatch;
                      end
                      end
                      qewqg = max([optgc(i-1,j-1) optgc(i-1,j-2)+app.gap optgc(i-2,j-1)+app.gap]);
                      if qewqg>0
                      optgc(i,j)=optgc(i,j)+qewqg;
                      end
                   end
                   if lseq1>i
                    for j=i+1:(lseq1)
                      if seq1(j)==seq2(i)
                      if optgc(j,i)>-Inf
                      optgc(j,i)=app.identite;
                      end
                      else
                      if optgc(j,i)>-Inf
                      optgc(j,i)=app.mismatch;
                      end
                      end
                      qewqg = max([optgc(j-1,i-1) optgc(j-1,i-2)+app.gap optgc(j-2,i-1)+app.gap]);
                      if qewqg>0
                      optgc(j,i)=optgc(j,i)+qewqg;
                      end
                    end
                   end
               end
            for i=1:lseq2
              mse1(i) = max(optgc(:,i));
            end
            for m = 2:lseq2
            if mse1(m)<mse1(m-1)
               mse1(m) = mse1(m-1);
            end
            end
           end
        resul = mse1;
        end
        
        function resulgqw = seqidentity3(app,seq3,seq7,eggggggg,v1,v2)%eggggggg=1 oligo_module eggggggg=2oligo_oligo
            gq=0;
          if (length(seq3)>2)&&(length(seq7)>2)
            seq1=seq3;
            seq2=seq7;
            for eggg=1:eggggggg
           lseq1 = length(seq1);
           lseq2 = length(seq2);
           seq1=fliplr(seq1);
           seq1=seqcomp(app,seq1);
           optgc = zeros(lseq1, lseq2);
           if ~isempty(v1)
               for i=1:length(v1)
                  optgc(v1(i),v2(i))=-Inf;
               end
           end
           for j=2:lseq2-1
               if seq2(j)==seq1(1)
                   if optgc(1,j)>-Inf
                   optgc(1,j)=app.identite;
                   optgc(2,j+1)=app.identite;
                   end
               end
           end
           if seq2(end)==seq1(1)
                   if optgc(1,lseq2)>-Inf
                   optgc(1,lseq2)=app.identite;
                   end
           end
               for i=2:lseq1
                   for j=3:lseq2
                             if optgc(i,j)>0
                                if seq1(i)==seq2(j)
                                    optgc(i,j)=optgc(i,j)+app.identite;
                                else
                                    optgc(i,j)=optgc(i,j)+app.mismatch;
                                end
                                if optgc(i,j)>0
                                    if (j<lseq2)&&(i<lseq1)
                                        optgc(i+1,j+1)=max(optgc(i,j),optgc(i+1,j+1));
                                        if j<lseq2-1
                                           if optgc(i+1,j+2)>-Inf
                                           optgc(i+1,j+2)=max(optgc(i,j)+app.gap,optgc(i+1,j+2)); 
                                           end
                                        end
                                        if i<lseq1-1
                                           if optgc(i+2,j+1)>-Inf
                                           optgc(i+2,j+1)=max(optgc(i,j)+app.gap,optgc(i+2,j+1)); 
                                           end
                                        end
                                    end
                                end
                             end
                   end
                   if (i<lseq1)&&(max(optgc(i+1,:))<=0)
                       break;
                   end
               end
               gq=max(gq,max(max(optgc)));
               seq1=seq7;
               seq2=seq3;
            end
          end
          resulgqw=gq;
        end
        
        function resul = seqmany(app,seq)
           ng=1;
           q='Y';
           mg=0;
           if length(seq)>1    
           mg=1;
           for i=1:length(seq)
           if seq(i)==q
           ng=ng+1;
           if ng>mg
           mg=ng;
           end
           else
           ng=1;
           q=seq(i);
           end
           end
           end
           resul=mg;
        end
        
        function [resul,mse1,weg] = penaltyoligo(app,tail,oligo,hmodif,smodif,gcmodif,v1,v2,fw)
%          penal = 0;
            mse=[];
            penalty = app.Taillep*abs(length(oligo) - app.Tailleopt)*app.Tailleweight;
            [tm,gc] = tmf(app,oligo,hmodif,smodif,gcmodif);
            penalty = penalty+app.Tmp*(tm-app.Tmopt)*(tm-app.Tmopt)*app.Tmweight;
            penalty = penalty+max(0,max((30-gc*100)/5,(gc*100-70)/5))*app.GCweight;
            mse=[mse max(0,max((30-gc*100)/5,(gc*100-70)/5))];
            tailed = [tail oligo]; 
            oligo_olig = seqidentity(app,tailed,tailed,[],[]);
            oligo_oligo = oligo_olig(end);
            penalty = penalty+max(0,(oligo_oligo-app.StrIImin))*app.StrIIweight;
            mse=[mse max(0,(oligo_oligo-app.StrIImin))];
            oligo_oligo3 = seqidentity3(app,tailed,tailed,2,[],[]);
            penalty = penalty+max(0,(oligo_oligo3-app.StrII3min))*app.StrII3weight;
            mse=[mse max(0,(oligo_oligo3-app.StrII3min))];
            oligo_modul31 = seqidentity3(app,tailed,app.modul{fw(1)},1,[],[]);
            oligo_modul32 = seqidentity3(app,tailed,app.modul{fw(2)},1,v1,v2);
            oligo_modul3 = max(oligo_modul31,oligo_modul32);
            penalty = penalty+max(0,(oligo_modul3-app.StrII3Matmin))*app.StrII3Matweight;
            mse=[mse max(0,(oligo_modul3-app.StrII3Matmin))];
            many = seqmany(app,oligo);
            penalty = penalty+max(0,(many-app.Repeatsmin))*app.Repeatsweight;
            mse=[mse max(0,(many-app.Repeatsmin))];
            seq3 = oligo(end-4:end);
            [tm3,gc3] = tmf(app,seq3,0,0,0);
            penalty = penalty+max(2-gc3*5,gc3*5-3)*app.GC3weight;
            mse=[mse max(2-gc3*5,gc3*5-3)];
            if tm3>10
                n = 2;
            else
                if tm3>0
                    n = 1;
                else
                    n = 0;
                end
            end
            penalty = penalty+n*app.Stability3weight;
            mse=[mse n];
            resul = penalty;
            mse1 = mse;
            weg = tm;
        end
        
    end

    methods (Access = public)
    
        function weighto(app,weg,linker)
          app.Tmopto = weg(1);
          app.Tmweighto = weg(2); 
          app.Tailleweighto = weg(3);
          app.StrIIweighto = weg(4);
          app.StrII3weighto = weg(5); 
          app.StrIIMatweighto = weg(6);
          app.StrII3Matweighto = weg(7);
          app.Repeatsweighto = weg(8); 
          app.GCweighto = weg(9);
          app.GC3weighto = weg(10);
          app.Stability3weighto = weg(11); 
          app.deltaTmweighto = weg(12);
          app.ascIfseIo = linker;
        end
        
        function buttonoption(app)
          app.OptionsButton.Enable = 'on';
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.myTimer=timer('StartDelay',0.3); 
        app.myTimer.TimerFcn = @(~,~)copchang(app);
       app.myTimer2=timer('StartDelay',0.3); 
        app.myTimer2.TimerFcn =  @(~,~)copchang2(app);
        app.myTimer3=timer('StartDelay',0.3); 
        app.myTimer3.TimerFcn = @(~,~)copchang3(app);
        app.myTimer4=timer('StartDelay',0.3); 
        app.myTimer4.TimerFcn = @(~,~)copchang4(app);
         wgg = imread('wgg.png' );
%           [x,y,z] =size(wgg)
%           app.UIAxes.Position(3)=y*1.1;
%           app.UIAxes.Position(4)=x*1.1;
            wI = imshow(wgg, 'Parent',app.UIAxes, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]) ;
          app.UIAxes.XLim = [0 wI.XData(2)];
          app.UIAxes.YLim = [0 wI.YData(2)];
%           wwg = imread('wwg.png' );
% %           [x,y,z] =size(wgg)
% %           app.UIAxes.Position(3)=y*1.1;
% %           app.UIAxes.Position(4)=x*1.1;
        end

        % Value changing function: ModulepositionSpinner
        function ModulepositionSpinnerValueChanging(app, event)
            Value = event.Value;
            if Value>1
            app.CheckBox.Visible = 'off'; 
            app.SingleintegrationLabel.Visible = 'off';
            else
            app.CheckBox.Visible = 'on';
            app.SingleintegrationLabel.Visible = 'on';
            end
            if Value==3
            app.CheckBox_2.Visible = 'on'; 
            app.Fusedtomodule4Label.Visible = 'on';
            else
            app.CheckBox_2.Visible = 'off'; 
            app.Fusedtomodule4Label.Visible = 'off';
            end
        end

        % Button pushed function: GeneratetailedprimersButton_2
        function GeneratetailedprimersButton_2Pushed(app, event)
            %app.GeneratetailedprimersButton.Enable = 'off' ;
            app.GeneratetailedprimersButton_2.Enable = 'off' ;
            l_homol = app.Forwardhomologoussequence53EditField.Value;
            r_homol = app.Reversehomologoussequence53EditField.Value;
            m_pos = app.ModulepositionSpinner_2.Value;
            stopcodon = app.CheckBox_3.Value;
            l_homol = upper(l_homol);
            r_homol = upper(r_homol);
            l_homol = strrep(l_homol,' ','');
            r_homol = strrep(r_homol,' ','');            
            lA = length(l_homol(l_homol=='A'));
            lT = length(l_homol(l_homol=='T'));
            lC = length(l_homol(l_homol=='C'));
            lG = length(l_homol(l_homol=='G'));
            rA = length(r_homol(r_homol=='A'));
            rT = length(r_homol(r_homol=='T'));
            rC = length(r_homol(r_homol=='C'));
            rG = length(r_homol(r_homol=='G'));
            l = lA+lT+lC+lG;
            r = rA+rT+rC+rG;
            if l<length(l_homol)||r<length(r_homol)||4>length(l_homol)||4>length(r_homol)
                qwe= sprintf('A/a T/t G/g C/c code required !\nMinimum 4 nt !');
                uialert(app.COSPLAY,    qwe, 'Wrong code');
               if 4>length(l_homol) app.Forwardhomologoussequence53EditField.BackgroundColor = [1 0.8 0.8] ;
                end
               if 4>length(r_homol) app.Reversehomologoussequence53EditField.BackgroundColor = [1 0.8 0.8] ;
                end
            else
           if (m_pos==3)&&(stopcodon==1)
           if strcmp(r_homol(1:3),'TCA')||strcmp(r_homol(1:3),'TTA')||strcmp(r_homol(1:3),'CTA')
                if length(r_homol)>6
               qwe1= sprintf('The reverse homologous sequence contains a STOP codon that will truncate the module 3 at the end !\nDo you want to remove it ?');
              selection1 = uiconfirm(app.COSPLAY,qwe1,'Select action',...
                        'Icon','warning','Options',{'Remove STOP','Keep STOP','Cancel'});
                    if strcmp(selection1,'Remove STOP')
                    r_homol = r_homol(4:end);
                    else
                        if strcmp(selection1,'Cancel')
                      %app.GeneratetailedprimersButton.Enable = 'on' ;
                        app.GeneratetailedprimersButton_2.Enable = 'on' ;
                        return
                        end
                    end
                else
           qwe1= sprintf('The reverse homologous sequence contains a STOP codon that will truncate the module 3 at the end ! However the STOP codon can not be removed because the homologous sequence is already too short !\nDo you want to continue ?');
              selection1 = uiconfirm(app.COSPLAY,qwe1,'Select action',...
                        'Icon','warning','Options',{'Keep STOP','Cancel'});
                    if strcmp(selection1,'Cancel')
             %app.GeneratetailedprimersButton.Enable = 'on' ;
            app.GeneratetailedprimersButton_2.Enable = 'on' ;
                    return
                    end 
                end
           end
 
           r_tailed = [app.right_puc app.right_sites{m_pos} 'CCTCC' r_homol];
           taild = [app.right_puc app.right_sites{m_pos} 'CCTCC' ];
           else
           r_tailed = [app.right_puc app.right_sites{m_pos} r_homol];
           taild = [app.right_puc app.right_sites{m_pos}   ];
           end
            rtaillength = length(r_tailed)-length(r_homol);
%             app.ClearButton.Enable = 'off' ;
%             app.CopyforwardprimerButton.Enable = 'off' ;
%             app.CopyreverseprimerButton.Enable = 'off' ;
%             app.SaveButton.Enable = 'off' ;
%             app.forwardp=[];
%             app.reversep=[];
%             app.TextArea_2.Value = '';
%             app.TextArea_2.FontColor = [0 0 0];
                l_tailed = [app.left_puc app.left_sites{m_pos} l_homol];
                taill = [app.left_puc app.left_sites{m_pos}   ];
                ltaillength = length(l_tailed)-length(l_homol);
                lbsa = bsaIfind(app,l_tailed);
                rbsa = bsaIfind(app,r_tailed);
                [wwwwwq,wwwwgg]=sort(lbsa{1});
            lbsa{1} = lbsa{1}(wwwwgg);
            lbsa{2} = lbsa{2}(wwwwgg);
            [wwwwwq,wwwwgg]=sort(rbsa{1});
            rbsa{1} = rbsa{1}(wwwwgg);
            rbsa{2} = rbsa{2}(wwwwgg);
                if (length(lbsa{1})==1)&&(length(rbsa{1})==1)
                                    l_tm = tmf(app,l_homol,0,0,0);%67+0.5*100*(lG+lC)/(lA+lT+lG+lC)-533/length(l_homol);
               r_tm = tmf(app,r_homol,0,0,0);
%                 l_tm = max(l_tm,-273.15);
%                 r_tm = max(r_tm,-273.15);
%                 l_tm = min(l_tm,72);
%                 r_tm = min(r_tm,72);
                if (lG+lC)/(lA+lT+lG+lC)<=0.2
                    gcl=', too low G/C %';
                else
                    if (lG+lC)/(lA+lT+lG+lC)>=0.8
                    gcl =', too high G/C %';
                    else
                    gcl ='';
                    end
                end
                if (rG+rC)/(rA+rT+rG+rC)<=0.2
                    rcl =', too low G/C %';
                else
                    if (rG+rC)/(rA+rT+rG+rC)>=0.8
                    rcl =', too high G/C %';
                    else
                    rcl='';
                    end
                end
                if length(l_homol)<=10
                    shortl =', too short homologous sequence';
                else
                    if length(l_homol)>45
                    shortl =', too long homologous sequence';
                    else
                    shortl ='';
                    end
                end
                if length(r_homol)<=10
                    shortr =', too short homologous sequence';
                else
                    if length(r_homol)>45
                    shortr =', too long homologous sequence';
                    else
                    shortr ='';
                    end
                end
                app.TextArea_2.Value = {['Forward tailed primer (Q5 pol Tm = ' num2str(l_tm,'%.1f') '°C' shortl gcl '):'] l_tailed '' ...
                ['Reverse tailed primer (Q5 pol Tm = ' num2str(r_tm,'%.1f') '°C' shortr rcl '):'] r_tailed};
                app.SaveButton.Enable = 'on' ;
                app.CopyforwardprimerButton.Enable = 'on' ;
                app.CopyreverseprimerButton.Enable = 'on' ;
                app.forwardp = l_tailed;
                app.reversep = r_tailed;
                else
                    lbsch = ((length(l_homol ) <6)||(lbsa{1}(end)>length(l_tailed)-6))&&(length(lbsa{1})>1) ;
                    rbsch = ((length(r_homol ) <6)||(rbsa{1}(end)>length(r_tailed)-6))&&(length(rbsa{1})>1) ;
                    if lbsch
                     if rbsch
                     uialert(app.COSPLAY,    'Both the forward and reverse primers contain a BsaI site which is too close to the 3'' end and can not be modified ! Indeed, all possible mutations should be at least 5 nt away from the 3'' end ! Please, extend the length of the primers ! '   , 'Intractable BsaI sites'); 
                     else
                     uialert(app.COSPLAY,    'The forward primer contains a BsaI site which is too close to the 3'' end and can not be modified ! Indeed, all possible mutations should be at least 5 nt away from the 3'' end ! Please, extend the length of this primer ! '   , 'Intractable BsaI site'); 
                     end
                     app.GeneratetailedprimersButton_2.Enable = 'on' ;
                    return
                    else
                    if rbsch
                    uialert(app.COSPLAY,    'The reverse primer contains a BsaI site which is too close to the 3'' end and can not be modified ! Indeed, all possible mutations should be at least 5 nt away from the 3'' end ! Please, extend the length of this primer ! '   , 'Intractable BsaI site'); 
                    app.GeneratetailedprimersButton_2.Enable = 'on' ;
                    return
                    end
                    end
                    hmodifl = 0;
                    hmodifr = 0;
                    smodifl = 0;
                    smodifr = 0;
                    gcmodifl = 0;
                    gcmodifr = 0;
                    if (length(lbsa{1})>1)
%                    =================================
                    app.RighttailLabel.Visible = 'off';
                         app.Label_7.Visible = 'off';
                         app.ModuleLabel.Text = 'Forward homologous sequence' ;
                     for wwi1 = 2:length(lbsa{1})
                         pause(0.1);
                         app.CheckBox_4.Enable = 'on';
                         app.CheckBox_5.Enable = 'on';
                         app.CheckBox_6.Enable = 'on';
                         app.CheckBox_7.Enable = 'on';
                         app.CheckBox_8.Enable = 'on';
                         app.CheckBox_9.Enable = 'on';
                         app.CheckBox_11.Enable = 'on';
                         app.CheckBox_12.Enable = 'on';
                         app.CheckBox_13.Enable = 'on';
                         app.CheckBox_14.Enable = 'on';
                         app.CheckBox_10.Enable = 'on';
                         app.CheckBox_15.Enable = 'on';
                         if lbsa{2}(wwi1) == 1
                             app.Panel_4.Visible = 'on';
                             app.Panel_5.Visible = 'off';
                             app.modchangew1 = 1;
                         else
                             app.Panel_4.Visible = 'off';
                             app.Panel_5.Visible = 'on';
                             app.modchangew1 = 2;
                         end
                         app.Label_5.Text = num2str(ltaillength + 1);
                         app.Label_6.Text = num2str(ltaillength + length(l_homol));
                         app.Label_7.Text = num2str(length(l_tailed));
                         if lbsa{1}(wwi1)>ltaillength
                         app.vLabel.Position(1) = 143 +(lbsa{1}(wwi1)-ltaillength-1)*(361-143)/(length(l_homol)-1);
                         else
                         app.vLabel.Position(1) = 143 +(lbsa{1}(wwi1)-ltaillength-1)*(143-70)/ltaillength;
                         end
                         app.BsaIposLabel.Position(1) = app.vLabel.Position(1)+1;
                         app.BsaIposLabel.Text = ['BsaI (' num2str(lbsa{1}(wwi1)) '>' num2str(lbsa{1}(wwi1)+5) ')'];
                         app.Label_22.Text = num2str(lbsa{1}(wwi1));
                         app.Label_23.Text = num2str(lbsa{1}(wwi1)+5);
                         app.GGTCTCLabel.Text = app.bsaI{lbsa{2}(wwi1)};
                         app.EditField.Value = ['5''...' l_tailed(lbsa{1}(wwi1)-19:lbsa{1}(wwi1)-1)];
                         if lbsa{1}(wwi1)+24>=length(l_tailed)
                         app.EditField_2.Value = [l_tailed(lbsa{1}(wwi1)+6:end) ' 3'''];
                         else
                         app.EditField_2.Value = [l_tailed(lbsa{1}(wwi1)+6:lbsa{1}(wwi1)+24) '...3'''];
                         end
                         if lbsa{1}(wwi1)<ltaillength
                         app.CheckBox_13.Enable = 'off';
                         app.CheckBox_7.Enable = 'off';
                         app.SomesilentmutationsLabel.Visible = 'on';
                         app.arenotavailablebecauseLabel.Visible = 'on';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'on';
                         app.linkersitepleaseselectLabel.Visible = 'on';
                         app.anothermutationorLabel.Visible = 'on';
                         app.manuallyalterthemoduleLabel.Visible = 'on';
                         app.sequenceLabel.Visible = 'on';
                         else
                         app.SomesilentmutationsLabel.Visible = 'off';
                         app.arenotavailablebecauseLabel.Visible = 'off';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'off';
                         app.linkersitepleaseselectLabel.Visible = 'off';
                         app.anothermutationorLabel.Visible = 'off';
                         app.manuallyalterthemoduleLabel.Visible = 'off';
                         app.sequenceLabel.Visible = 'off';
                         end
                         if lbsa{1}(wwi1)<ltaillength-1
                         app.CheckBox_14.Enable = 'off';
                         app.CheckBox_10.Enable = 'off';
                         app.CheckBox_8.Enable = 'off';
                         app.CheckBox_4.Enable = 'off';
                         end
                         if lbsa{1}(wwi1)>length(l_tailed)-9
                         app.SomesilentmutationsLabel_2.Visible = 'on';
                         app.arenotavailablebecauseLabel_2.Visible = 'on';
                         app.themutationsshouldbeatLabel.Visible = 'on';
                         app.least5ntawayfromthe3Label.Visible = 'on';
                         app.endpleaseselectanotherLabel.Visible = 'on';
                         app.mutationorextendtheLabel.Visible = 'on';
                         app.homologoussequenceLabel.Visible = 'on';
                         app.CheckBox_6.Enable = 'off';
                         app.CheckBox_12.Enable = 'off';
                         else
                         app.SomesilentmutationsLabel_2.Visible = 'off';
                         app.arenotavailablebecauseLabel_2.Visible = 'off';
                         app.themutationsshouldbeatLabel.Visible = 'off';
                         app.least5ntawayfromthe3Label.Visible = 'off';
                         app.endpleaseselectanotherLabel.Visible = 'off';
                         app.mutationorextendtheLabel.Visible = 'off';
                         app.homologoussequenceLabel.Visible = 'off';
                         end
                         if lbsa{1}(wwi1)>length(l_tailed)-8
                         app.CheckBox_5.Enable = 'off';
                         app.CheckBox_9.Enable = 'off';
                         app.CheckBox_11.Enable = 'off';
                         app.CheckBox_15.Enable = 'off';
                         end
                         if lbsa{1}(wwi1)>length(l_tailed)-7
                         app.CheckBox_4.Enable = 'off';
                         app.CheckBox_8.Enable = 'off';
                         app.CheckBox_10.Enable = 'off';
                         app.CheckBox_14.Enable = 'off';
                         end
                         app.Panel.Visible = 'on';
                         hhw = app.GGLabel;
                         uiwait(app.COSPLAY);
                         if ~ishandle(hhw)
                         return
                         end
                         if lbsa{1}(wwi1)-ltaillength+app.positionchanw(app.modchangew2) ==1
                         hmodifl = hmodifl+app.lhcorec(app.modchangew1,app.modchangew2);
                         smodifl = smodifl+app.lscorec(app.modchangew1,app.modchangew2);    
                         else
                         hmodifl = hmodifl+app.hcorr(app.modchangew1,app.modchangew2);
                         smodifl = smodifl+app.scorr(app.modchangew1,app.modchangew2);
                         end
                         gcmodifl = gcmodifl+app.gccorr(app.modchangew1,app.modchangew2);
                         l_homol(lbsa{1}(wwi1)-ltaillength+app.positionchanw(app.modchangew2)) = app.modchangew3;
                     l_tailed = [taill l_homol];
                     end
%                   ===============================
                    end
                    if (length(rbsa{1})>1) 
                      app.RighttailLabel.Visible = 'off';
                         app.Label_7.Visible = 'off';
                         app.ModuleLabel.Text = 'Reverse homologous sequence' ;
                     for wwi1 = 2:length(rbsa{1})
                         pause(0.1);
                         app.CheckBox_4.Enable = 'on';
                         app.CheckBox_5.Enable = 'on';
                         app.CheckBox_6.Enable = 'on';
                         app.CheckBox_7.Enable = 'on';
                         app.CheckBox_8.Enable = 'on';
                         app.CheckBox_9.Enable = 'on';
                         app.CheckBox_11.Enable = 'on';
                         app.CheckBox_12.Enable = 'on';
                         app.CheckBox_13.Enable = 'on';
                         app.CheckBox_14.Enable = 'on';
                         app.CheckBox_10.Enable = 'on';
                         app.CheckBox_15.Enable = 'on';
                         if rbsa{2}(wwi1) == 1
                             app.Panel_4.Visible = 'on';
                             app.Panel_5.Visible = 'off';
                             app.modchangew1 = 1;
                         else
                             app.Panel_4.Visible = 'off';
                             app.Panel_5.Visible = 'on';
                             app.modchangew1 = 2;
                         end
                         app.Label_5.Text = num2str(rtaillength + 1);
                         app.Label_6.Text = num2str(rtaillength + length(r_homol));
                         app.Label_7.Text = num2str(length(r_tailed));
                         if rbsa{1}(wwi1)>ltaillength
                         app.vLabel.Position(1) = 143 +(rbsa{1}(wwi1)-rtaillength-1)*(361-143)/(length(r_homol)-1);
                         else
                         app.vLabel.Position(1) = 143 +(rbsa{1}(wwi1)-rtaillength-1)*(143-70)/rtaillength;
                         end
                         app.BsaIposLabel.Position(1) = app.vLabel.Position(1)+1;
                         app.BsaIposLabel.Text = ['BsaI (' num2str(rbsa{1}(wwi1)) '>' num2str(rbsa{1}(wwi1)+5) ')'];
                         app.Label_22.Text = num2str(rbsa{1}(wwi1));
                         app.Label_23.Text = num2str(rbsa{1}(wwi1)+5);
                         app.GGTCTCLabel.Text = app.bsaI{rbsa{2}(wwi1)};
                         app.EditField.Value = ['5''...' r_tailed(rbsa{1}(wwi1)-19:rbsa{1}(wwi1)-1)];
                         if rbsa{1}(wwi1)+24>=length(r_tailed)
                         app.EditField_2.Value = [r_tailed(rbsa{1}(wwi1)+6:end) ' 3'''];
                         else
                         app.EditField_2.Value = [r_tailed(rbsa{1}(wwi1)+6:rbsa{1}(wwi1)+24) '...3'''];
                         end
                         if rbsa{1}(wwi1)<rtaillength
                         app.CheckBox_13.Enable = 'off';
                         app.CheckBox_7.Enable = 'off';
                         app.SomesilentmutationsLabel.Visible = 'on';
                         app.arenotavailablebecauseLabel.Visible = 'on';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'on';
                         app.linkersitepleaseselectLabel.Visible = 'on';
                         app.anothermutationorLabel.Visible = 'on';
                         app.manuallyalterthemoduleLabel.Visible = 'on';
                         app.sequenceLabel.Visible = 'on';
                         else
                         app.SomesilentmutationsLabel.Visible = 'off';
                         app.arenotavailablebecauseLabel.Visible = 'off';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'off';
                         app.linkersitepleaseselectLabel.Visible = 'off';
                         app.anothermutationorLabel.Visible = 'off';
                         app.manuallyalterthemoduleLabel.Visible = 'off';
                         app.sequenceLabel.Visible = 'off';
                         end
                         if rbsa{1}(wwi1)<rtaillength-1
                         app.CheckBox_14.Enable = 'off';
                         app.CheckBox_10.Enable = 'off';
                         app.CheckBox_8.Enable = 'off';
                         app.CheckBox_4.Enable = 'off';
                         end
                         if rbsa{1}(wwi1)>length(r_tailed)-9
                         app.SomesilentmutationsLabel_2.Visible = 'on';
                         app.arenotavailablebecauseLabel_2.Visible = 'on';
                         app.themutationsshouldbeatLabel.Visible = 'on';
                         app.least5ntawayfromthe3Label.Visible = 'on';
                         app.endpleaseselectanotherLabel.Visible = 'on';
                         app.mutationorextendtheLabel.Visible = 'on';
                         app.homologoussequenceLabel.Visible = 'on';
                         app.CheckBox_6.Enable = 'off';
                         app.CheckBox_12.Enable = 'off';
                         else
                         app.SomesilentmutationsLabel_2.Visible = 'off';
                         app.arenotavailablebecauseLabel_2.Visible = 'off';
                         app.themutationsshouldbeatLabel.Visible = 'off';
                         app.least5ntawayfromthe3Label.Visible = 'off';
                         app.endpleaseselectanotherLabel.Visible = 'off';
                         app.mutationorextendtheLabel.Visible = 'off';
                         app.homologoussequenceLabel.Visible = 'off';
                         end
                         if rbsa{1}(wwi1)>length(r_tailed)-8
                         app.CheckBox_5.Enable = 'off';
                         app.CheckBox_9.Enable = 'off';
                         app.CheckBox_11.Enable = 'off';
                         app.CheckBox_15.Enable = 'off';
                         end
                         if rbsa{1}(wwi1)>length(r_tailed)-7
                         app.CheckBox_4.Enable = 'off';
                         app.CheckBox_8.Enable = 'off';
                         app.CheckBox_10.Enable = 'off';
                         app.CheckBox_14.Enable = 'off';
                         end
                         app.Panel.Visible = 'on';
                         hhw = app.GGLabel;
                         uiwait(app.COSPLAY);
                         if ~ishandle(hhw)
                         return
                         end
                         if rbsa{1}(wwi1)-rtaillength+app.positionchanw(app.modchangew2) ==1
                        hmodifr = hmodifr+app.lhcorec(app.modchangew1,app.modchangew2);
                         smodifr = smodifr+app.lscorec(app.modchangew1,app.modchangew2);
                         else
                         hmodifr = hmodifr+app.hcorr(app.modchangew1,app.modchangew2);
                         smodifr = smodifr+app.scorr(app.modchangew1,app.modchangew2);
                         end
                         gcmodifr = gcmodifr+app.gccorr(app.modchangew1,app.modchangew2);
                         r_homol(rbsa{1}(wwi1)-rtaillength+app.positionchanw(app.modchangew2)) = app.modchangew3;
                     r_tailed = [taild r_homol];
                     end
                    end
                                                        [l_tm,wl_gc] = tmf(app,l_homol,hmodifl,smodifl,gcmodifl);%67+0.5*100*(lG+lC)/(lA+lT+lG+lC)-533/length(l_homol);
               [r_tm,wr_gc] = tmf(app,r_homol,hmodifr,smodifr,gcmodifr);
                if wl_gc<=0.2
                    gcl=', too low G/C %';
                else
                    if wl_gc>=0.8
                    gcl =', too high G/C %';
                    else
                    gcl ='';
                    end
                end
                if wr_gc<=0.2
                    rcl =', too low G/C %';
                else
                    if wr_gc>=0.8
                    rcl =', too high G/C %';
                    else
                    rcl='';
                    end
                end
                if length(l_homol)<=10
                    shortl =', too short homologous sequence';
                else
                    if length(l_homol)>45
                    shortl =', too long homologous sequence';
                    else
                    shortl ='';
                    end
                end
                if length(r_homol)<=10
                    shortr =', too short homologous sequence';
                else
                    if length(r_homol)>45
                    shortr =', too long homologous sequence';
                    else
                    shortr ='';
                    end
                end
                app.TextArea_2.Value = {['Forward tailed primer (Q5 pol Tm = ' num2str(l_tm,'%.1f') '°C' shortl gcl '):'] l_tailed '' ...
                ['Reverse tailed primer (Q5 pol Tm = ' num2str(r_tm,'%.1f') '°C' shortr rcl '):'] r_tailed};
                app.SaveButton.Enable = 'on' ;
                app.CopyforwardprimerButton.Enable = 'on' ;
                app.CopyreverseprimerButton.Enable = 'on' ;
                app.forwardp = l_tailed;
                app.reversep = r_tailed;
                end
               app.ClearButton.Enable = 'on' ;
            end
            %app.GeneratetailedprimersButton.Enable = 'on' ;
            app.GeneratetailedprimersButton_2.Enable = 'on' ;
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            m_homol = [value{:}];
            m_homol = upper(m_homol);
            m_homol = strrep(m_homol,' ','');
            m = length(m_homol(m_homol=='A'));
            m = m+length(m_homol(m_homol=='T'));
            m = m+length(m_homol(m_homol=='C'));
            m = m+length(m_homol(m_homol=='G'));
            if  50>length(m_homol)
                app.TextArea.BackgroundColor = [1 0.8 0.8] ;
            else
                if m<length(m_homol)
                app.TextArea.BackgroundColor = [1 0.8 0.8] ;
                else
                app.TextArea.BackgroundColor = [0.8 1 0.8 ] ;
                end
            end
        end

        % Value changed function: Forwardhomologoussequence53EditField
        function Forwardhomologoussequence53EditFieldValueChanged(app, event)
            l_homol = app.Forwardhomologoussequence53EditField.Value;
            l_homol = upper(l_homol);
            l_homol = strrep(l_homol,' ','');            
            l = length(l_homol(l_homol=='A'));
            l = l+length(l_homol(l_homol=='T'));
            l = l+length(l_homol(l_homol=='C'));
            l = l+length(l_homol(l_homol=='G'));
            if  4>length(l_homol)
                app.Forwardhomologoussequence53EditField.BackgroundColor = [1 0.8 0.8] ;
            else
                if l<length(l_homol)
                app.Forwardhomologoussequence53EditField.BackgroundColor = [1 0.8 0.8] ;
                else
                app.Forwardhomologoussequence53EditField.BackgroundColor = [0.8 1 0.8 ] ;
                end
            end
        end

        % Value changed function: Reversehomologoussequence53EditField
        function Reversehomologoussequence53EditFieldValueChanged(app, event)
            r_homol = app.Reversehomologoussequence53EditField.Value;
            r_homol = upper(r_homol);
            r_homol = strrep(r_homol,' ','');              
            r = length(r_homol(r_homol=='A'));
            r = r+length(r_homol(r_homol=='T'));
            r = r+length(r_homol(r_homol=='C'));
            r = r+length(r_homol(r_homol=='G'));
            if  4>length(r_homol)
                app.Reversehomologoussequence53EditField.BackgroundColor = [1 0.8 0.8] ;
            else
                if r<length(r_homol)
                app.Reversehomologoussequence53EditField.BackgroundColor = [1 0.8 0.8] ;
                else
                app.Reversehomologoussequence53EditField.BackgroundColor = [0.8 1 0.8 ] ;
                end
            end
        end

        % Button pushed function: GeneratetailedprimersButton
        function GeneratetailedprimersButtonPushed(app, event)
            app.GeneratetailedprimersButton.Enable = 'off' ;
            app.GeneratetailedprimersButton_2.Enable = 'off' ;
            m_pos = app.ModulepositionSpinner.Value;
            value = app.TextArea.Value;
            single = app.CheckBox.Value;
            stopcodon = app.CheckBox_2.Value;
            m_homol = [value{:}];
            m_homol = upper(m_homol);
            app.Tmopt = app.Tmopto;
            app.Tmweight = app.Tmweighto; 
            app.Tailleweight = app.Tailleweighto;
            app.StrIIweight = app.StrIIweighto;
            app.StrII3weight = app.StrII3weighto; 
            app.StrIIMatweight = app.StrIIMatweighto;
            app.StrII3Matweight = app.StrII3Matweighto;
            app.Repeatsweight = app.Repeatsweighto; 
            app.GCweight = app.GCweighto;
            app.GC3weight = app.GC3weighto;
            app.Stability3weight = app.Stability3weighto;
            app.deltaTmweight = app.deltaTmweighto;
            app.ascIfseI = app.ascIfseIo;
            m_homol = strrep(m_homol,' ','');
            m = length(m_homol(m_homol=='A'));
            m = m+length(m_homol(m_homol=='T'));
            m = m+length(m_homol(m_homol=='C'));
            m = m+length(m_homol(m_homol=='G'));
            if m<length(m_homol)||50>length(m_homol)
                if (m_pos==1)&&(single==1)
                qwe= sprintf('A/a T/t G/g C/c code required !\nMinimum 80 nt for single integration!');
                else
                qwe= sprintf('A/a T/t G/g C/c code required !\nMinimum 50 nt !');
                end
                uialert(app.COSPLAY,    qwe, 'Wrong code');
               if 50>length(m_homol) app.TextArea.BackgroundColor = [1 0.8 0.8] ;
               end
            else
%           ================================================================================================
if (m_pos==1)&&(single==1)
    if 80>length(m_homol)
    uialert(app.COSPLAY,    'Minimum 80 nt for single integration!', 'Short sequence');
    app.GeneratetailedprimersButton.Enable = 'on' ;
    app.GeneratetailedprimersButton_2.Enable = 'on' ;
    return
    end
    if strcmp(app.ascIfseI,'GGCGCGCCGGCCGGCC')
        r_taile = [app.right_puc app.right_sites{m_pos}];
        r_taile = fliplr(r_taile);
        r_taile = seqcomp(app,r_taile);
 recombh = [app.left_puc app.left_sites{m_pos} m_homol(end-39:end) app.ascIfseI m_homol(1:40) r_taile];
        if (length(strfind(recombh,'GGCGCGCC'))>1)
            if (length(strfind(recombh,'GGCCGGCC'))>1)
        qew12 = sprintf('The final inverted module contains additional restriction sites for both AscI and FseI enzymes ! You have to use another restriction enzyme to cut the single interation plasmid !\nOpen the Options menu and enter another restriction enzyme site in the single integration parameters !');
        uialert(app.COSPLAY,  qew12 , 'Bad linker');
        app.GeneratetailedprimersButton.Enable = 'on' ;
    app.GeneratetailedprimersButton_2.Enable = 'on' ;
            return
            else
                a1w1 = 1;
            end
        else
        if (length(strfind(recombh,'GGCCGGCC'))>1)
            a1w1 = 2;
        else
            a1w1 = 0 ;
        end
        end
    else
   r_taile = [app.right_puc app.right_sites{m_pos}];
        r_taile = fliplr(r_taile);
        r_taile = seqcomp(app,r_taile);
 recombh = [app.left_puc app.left_sites{m_pos} m_homol(end-39:end) app.ascIfseI m_homol(1:40) r_taile];
       if (length(strfind(recombh,app.ascIfseI))>1)
        qew12 = sprintf('The final inverted module contains additional restriction sites for the enzyme that you have choosed ! You have to use another restriction enzyme to cut the single interation plasmid !\nOpen the Options menu and enter another restriction enzyme site in the single integration parameters !');
        uialert(app.COSPLAY,  qew12 , 'Bad linker');
        app.GeneratetailedprimersButton.Enable = 'on' ;
    app.GeneratetailedprimersButton_2.Enable = 'on' ;
        return
       else
           a1w1 = 3;
        end
    end
    bwwwgg = bsaIfind(app,recombh);
    if length(bwwwgg{1})>2
    uialert(app.COSPLAY,  'The final inverted module contains at least one BsaI internal site. The final module is generated by inverting the first and last 40 bp of the initial module. Please alter these sequences in order to eliminate the additional BsaI sites !' , 'BsaI internal sites');
        app.GeneratetailedprimersButton.Enable = 'on' ;
    app.GeneratetailedprimersButton_2.Enable = 'on' ;
    return
    end
    l_tailed1 = recombh(1:96);
    r_tailed1 = recombh(end-95:end);
    r_tailed1 = fliplr(r_tailed1);
    r_tailed1 = seqcomp(app,r_tailed1);
    if a1w1==1
   wwe12 = 'The site AscI is present more than 2 times but instead you can use FseI to open the single integration plasmid.'; 
    else
      if a1w1==2
   wwe12 = 'The site FseI is present more than 2 times but instead you can use AscI to open the single integration plasmid.';
      else
         if a1w1==3
  wwe12 = ['Use the restriction enzyme recognising the site ' app.ascIfseI ' that you have selected to open the single integration plasmid 8 !'];
         else
  wwe12 = 'Use AscI or FseI to open the single integration plasmid !';       
         end
      end
    end
    app.logg2 ={'Forward tailed primer:' l_tailed1 '' 'Reverse tailed primer:' r_tailed1 '' wwe12};
    app.TextArea_3.Value = {'Forward tailed primer:' l_tailed1 '' 'Reverse tailed primer:' r_tailed1 '' wwe12}; 
app.SaveButton_2.Enable = 'on' ;
            app.CopyforwardprimerButton_2.Enable = 'on' ;
            app.CopyreverseprimerButton_2.Enable = 'on' ;
            app.ClearButton_2.Enable = 'on';
            app.forwardp2=l_tailed1 ;
            app.reversep2=r_tailed1;
    app.GeneratetailedprimersButton.Enable = 'on' ;
 app.GeneratetailedprimersButton_2.Enable = 'on' ;
 return
end
           if (m_pos==3)&&(stopcodon==1)
           r_m = m_homol(end-2:end);
           if strcmp(r_m,'TGA')||strcmp(r_m,'TAA')||strcmp(r_m,'TAG')
                if length(m_homol)>52
               qwe1= sprintf('The module sequence contains a STOP codon at the end !\nDo you want to remove it ?');
              selection1 = uiconfirm(app.COSPLAY,qwe1,'Select action',...
                        'Icon','warning','Options',{'Remove STOP','Keep STOP','Cancel'});
                    if strcmp(selection1,'Remove STOP')
                    m_homol = m_homol(1:end-3);
                    else
                        if strcmp(selection1,'Cancel')
             app.GeneratetailedprimersButton.Enable = 'on' ;
            app.GeneratetailedprimersButton_2.Enable = 'on' ;
                        return
                        end
                    end
                else
           qwe1= sprintf('The module sequence contains a STOP codon at the end ! However the STOP codon can not be removed because the module sequence is already too short !\nDo you want to continue ?');
              selection1 = uiconfirm(app.COSPLAY,qwe1,'Select action',...
                        'Icon','warning','Options',{'Keep STOP','Cancel'});
                    if strcmp(selection1,'Cancel')
             app.GeneratetailedprimersButton.Enable = 'on' ;
            app.GeneratetailedprimersButton_2.Enable = 'on' ;
                    return
                    end 
                end
           end
           r_tailed = [app.right_puc app.right_sites{m_pos} 'CCTCC'];
           else
           r_tailed = [app.right_puc app.right_sites{m_pos}];
            end      
            app.WaitplsLabel.Visible = 'on';
            drawnow;
                  app.ClearButton_2.Enable = 'off' ;
            app.CopyforwardprimerButton_2.Enable = 'off' ;
            app.CopyreverseprimerButton_2.Enable = 'off' ;
            app.SaveButton_2.Enable = 'off' ;
            app.forwardp2=[];
            app.reversep2=[];
            app.TextArea_3.Value = '';
            app.TextArea_3.FontColor = [0 0 0];
            l_tailed = [app.left_puc app.left_sites{m_pos}];
           ltailedlength = length(l_tailed);
            rtailedlength = length(r_tailed);
%           ------------------------------------------------------------------------------------------------
            noin = 1;
            hmodifl = 0;
            smodifl = 0;
            gcmodifl = 0;
            hmodifr = 0;
            smodifr = 0;
            gcmodifr = 0;
            min_left = app.Taillemin;
            min_right = app.Taillemin;
            max_left = app.Taillemax;
            max_right = app.Taillemax;
            retailedrw = seqcomp(app,r_tailed);
            retailedrw = fliplr(retailedrw);
            fullqw = [l_tailed m_homol retailedrw];
            bsaw = bsaIfind(app,fullqw);
            [wwwwwq,wwwwgg]=sort(bsaw{1});
            bsaw{1} = bsaw{1}(wwwwgg);
            bsaw{2} = bsaw{2}(wwwwgg);
            if length(bsaw{1}) == 2
            hom_left_max = m_homol(1:max_left);
            hom_right_max = m_homol(end-max_right+1:end);
            else
                app.SomesilentmutationsLabel_2.Visible = 'off';
                         app.arenotavailablebecauseLabel_2.Visible = 'off';
                         app.themutationsshouldbeatLabel.Visible = 'off';
                         app.least5ntawayfromthe3Label.Visible = 'off';
                         app.endpleaseselectanotherLabel.Visible = 'off';
                         app.mutationorextendtheLabel.Visible = 'off';
                         app.homologoussequenceLabel.Visible = 'off';
                         app.RighttailLabel.Visible = 'on';
                         app.Label_7.Visible = 'on';
                         app.ModuleLabel.Text = 'Module' ;
               if length(m_homol)>95
                   modif_ho = m_homol;
                   for wwi1 = 2:length(bsaw{1})-1
                      if bsaw{1}(wwi1)-ltailedlength<40
                         pause(0.1);
                         app.CheckBox_4.Enable = 'on';
                         app.CheckBox_5.Enable = 'on';
                         app.CheckBox_6.Enable = 'on';
                         app.CheckBox_7.Enable = 'on';
                         app.CheckBox_8.Enable = 'on';
                         app.CheckBox_9.Enable = 'on';
                         app.CheckBox_11.Enable = 'on';
                         app.CheckBox_12.Enable = 'on';
                         app.CheckBox_13.Enable = 'on';
                         app.CheckBox_14.Enable = 'on';
                         app.CheckBox_10.Enable = 'on';
                         app.CheckBox_15.Enable = 'on';
                         if bsaw{2}(wwi1) == 1
                             app.Panel_4.Visible = 'on';
                             app.Panel_5.Visible = 'off';
                             app.modchangew1 = 1;
                         else
                             app.Panel_4.Visible = 'off';
                             app.Panel_5.Visible = 'on';
                             app.modchangew1 = 2;
                         end
                         app.Label_5.Text = num2str(ltailedlength + 1);
                         app.Label_6.Text = num2str(ltailedlength + length(m_homol));
                         app.Label_7.Text = num2str(ltailedlength + length(m_homol) + rtailedlength);
                         if bsaw{1}(wwi1)>ltailedlength
                         app.vLabel.Position(1) = 143 +(bsaw{1}(wwi1)-ltailedlength-1)*(361-143)/(length(m_homol)-1);
                         else
                         app.vLabel.Position(1) = 143 +(bsaw{1}(wwi1)-ltailedlength-1)*(143-70)/ltailedlength;
                         end
                         app.BsaIposLabel.Position(1) = app.vLabel.Position(1)+1;
                         app.BsaIposLabel.Text = ['BsaI (' num2str(bsaw{1}(wwi1)) '>' num2str(bsaw{1}(wwi1)+5) ')'];
                         app.Label_22.Text = num2str(bsaw{1}(wwi1));
                         app.Label_23.Text = num2str(bsaw{1}(wwi1)+5);
                         app.GGTCTCLabel.Text = app.bsaI{bsaw{2}(wwi1)};
                         app.EditField.Value = ['5''...' fullqw(bsaw{1}(wwi1)-19:bsaw{1}(wwi1)-1)];
                         app.EditField_2.Value = [fullqw(bsaw{1}(wwi1)+6:bsaw{1}(wwi1)+24) '...3'''];
                         if bsaw{1}(wwi1)<ltailedlength
                         app.CheckBox_13.Enable = 'off';
                         app.CheckBox_7.Enable = 'off';
                         app.SomesilentmutationsLabel.Visible = 'on';
                         app.arenotavailablebecauseLabel.Visible = 'on';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'on';
                         app.linkersitepleaseselectLabel.Visible = 'on';
                         app.anothermutationorLabel.Visible = 'on';
                         app.manuallyalterthemoduleLabel.Visible = 'on';
                         app.sequenceLabel.Visible = 'on';
                         else
                         app.SomesilentmutationsLabel.Visible = 'off';
                         app.arenotavailablebecauseLabel.Visible = 'off';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'off';
                         app.linkersitepleaseselectLabel.Visible = 'off';
                         app.anothermutationorLabel.Visible = 'off';
                         app.manuallyalterthemoduleLabel.Visible = 'off';
                         app.sequenceLabel.Visible = 'off';
                         end
                         if bsaw{1}(wwi1)<ltailedlength-1
                         app.CheckBox_14.Enable = 'off';
                         app.CheckBox_10.Enable = 'off';
                         app.CheckBox_8.Enable = 'off';
                         app.CheckBox_4.Enable = 'off';
                         end
                         app.Panel.Visible = 'on';
                         hhw = app.GGLabel;
                         uiwait(app.COSPLAY);
                         if ~ishandle(hhw)
                         return
                         end
                         if bsaw{1}(wwi1)-ltailedlength+app.positionchanw(app.modchangew2) ==1
                             hmodifl = hmodifl+app.lhcorec(app.modchangew1,app.modchangew2);
                         smodifl = smodifl+app.lscorec(app.modchangew1,app.modchangew2);
                         else
                         if  bsaw{1}(wwi1)-ltailedlength+app.positionchanw(app.modchangew2) == length(modif_ho)
                             hmodifl = hmodifl+app.rhcorec(app.modchangew1,app.modchangew2);
                         smodifl = smodifl+app.rscorec(app.modchangew1,app.modchangew2);
                         else
                         hmodifl = hmodifl+app.hcorr(app.modchangew1,app.modchangew2);
                         smodifl = smodifl+app.scorr(app.modchangew1,app.modchangew2);
                         end
                         end
                         gcmodifl = gcmodifl+app.gccorr(app.modchangew1,app.modchangew2);
                         if min_left<bsaw{1}(wwi1)+app.positionchanw(app.modchangew2)-ltailedlength+5
                         min_left=bsaw{1}(wwi1)+app.positionchanw(app.modchangew2)-ltailedlength+5;
                         end
                         if max_left<min_left
                         max_left =min_left;
                         end
                         modif_ho(bsaw{1}(wwi1)-ltailedlength+app.positionchanw(app.modchangew2)) = app.modchangew3;
                      end
%                       ===================
%                       ===================
                       if ltailedlength+length(m_homol)-(bsaw{1}(wwi1)+5)<39
                         pause(0.1);
                         app.CheckBox_4.Enable = 'on';
                         app.CheckBox_5.Enable = 'on';
                         app.CheckBox_6.Enable = 'on';
                         app.CheckBox_7.Enable = 'on';
                         app.CheckBox_8.Enable = 'on';
                         app.CheckBox_9.Enable = 'on';
                         app.CheckBox_11.Enable = 'on';
                         app.CheckBox_12.Enable = 'on';
                         app.CheckBox_13.Enable = 'on';
                         app.CheckBox_14.Enable = 'on';
                         app.CheckBox_10.Enable = 'on';
                         app.CheckBox_15.Enable = 'on';
                         if bsaw{2}(wwi1) == 1
                             app.Panel_4.Visible = 'on';
                             app.Panel_5.Visible = 'off';
                             app.modchangew1 = 2;
                         else
                             app.Panel_4.Visible = 'off';
                             app.Panel_5.Visible = 'on';
                             app.modchangew1 = 1;
                         end
                         app.Label_5.Text = num2str(ltailedlength + 1);
                         app.Label_6.Text = num2str(ltailedlength + length(m_homol));
                         app.Label_7.Text = num2str(ltailedlength + length(m_homol) + rtailedlength);
                         if bsaw{1}(wwi1)<ltailedlength+length(m_homol)+1
                         app.vLabel.Position(1) = 143 +(bsaw{1}(wwi1)-ltailedlength-1)*(361-143)/(length(m_homol)-1);
                         else
                         app.vLabel.Position(1) = 361 +(bsaw{1}(wwi1)-ltailedlength-length(m_homol))*(143-70)/rtailedlength;
                         end
                         app.BsaIposLabel.Position(1) = app.vLabel.Position(1)+ 1;
                         app.BsaIposLabel.Text = ['BsaI (' num2str(bsaw{1}(wwi1)) '>' num2str(bsaw{1}(wwi1)+5) ')'];
                         app.Label_22.Text = num2str(bsaw{1}(wwi1));
                         app.Label_23.Text = num2str(bsaw{1}(wwi1)+5);
                         app.GGTCTCLabel.Text = app.bsaI{bsaw{2}(wwi1)};
                         app.EditField.Value = ['5''...' fullqw(bsaw{1}(wwi1)-19:bsaw{1}(wwi1)-1)];
                         app.EditField_2.Value = [fullqw(bsaw{1}(wwi1)+6:bsaw{1}(wwi1)+24) '...3'''];
                         if bsaw{1}(wwi1)+5>ltailedlength+length(m_homol)+1
                         app.CheckBox_6.Enable = 'off';
                         app.CheckBox_12.Enable = 'off';
                         app.SomesilentmutationsLabel.Visible = 'on';
                         app.arenotavailablebecauseLabel.Visible = 'on';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'on';
                         app.linkersitepleaseselectLabel.Visible = 'on';
                         app.anothermutationorLabel.Visible = 'on';
                         app.manuallyalterthemoduleLabel.Visible = 'on';
                         app.sequenceLabel.Visible = 'on';
                         else
                         app.SomesilentmutationsLabel.Visible = 'off';
                         app.arenotavailablebecauseLabel.Visible = 'off';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'off';
                         app.linkersitepleaseselectLabel.Visible = 'off';
                         app.anothermutationorLabel.Visible = 'off';
                         app.manuallyalterthemoduleLabel.Visible = 'off';
                         app.sequenceLabel.Visible = 'off';
                         end
                         if bsaw{1}(wwi1)+5>ltailedlength+length(m_homol)+2
                         app.CheckBox_15.Enable = 'off';
                         app.CheckBox_11.Enable = 'off';
                         app.CheckBox_9.Enable = 'off';
                         app.CheckBox_5.Enable = 'off';
                         end
                         app.Panel.Visible = 'on';
                         hhw = app.GGLabel;
                         uiwait(app.COSPLAY);
                         if ~ishandle(hhw)
                         return
                         end 
                         if bsaw{1}(wwi1)-ltailedlength+app.positionchanw(app.modchangew2)==1
                         hmodifr = hmodifr+app.lhcorec(bsaw{2}(wwi1),app.modchangew2);
                         smodifr = smodifr+app.lscorec(bsaw{2}(wwi1),app.modchangew2);
                         else
                         if bsaw{1}(wwi1)-ltailedlength+app.positionchanw(app.modchangew2)==length(modif_ho)
                             hmodifr = hmodifr+app.rhcorec(bsaw{2}(wwi1),app.modchangew2);
                         smodifr = smodifr+app.rscorec(bsaw{2}(wwi1),app.modchangew2);
                         else
                         hmodifr = hmodifr+app.hcorr(app.modchangew1,7-app.modchangew2);
                         smodifr = smodifr+app.scorr(app.modchangew1,7-app.modchangew2);
                         end
                         end
                         gcmodifr = gcmodifr+app.gccorr(app.modchangew1,7-app.modchangew2);
                         if min_right<length(m_homol)+ltailedlength-(bsaw{1}(wwi1)+app.positionchanw(app.modchangew2)-5)+1
                         min_right=length(m_homol)+ltailedlength-(bsaw{1}(wwi1)+app.positionchanw(app.modchangew2)-5)+1;
                         end
                         if max_right<min_right
                         max_right =min_right;
                         end
                         modif_ho(bsaw{1}(wwi1)-ltailedlength+app.positionchanw(app.modchangew2)) = app.modchangew3;
                      end
%                        ===================
%                       ===================
                          if ~(ltailedlength+length(m_homol)-(bsaw{1}(wwi1)+5)<39)&&~(bsaw{1}(wwi1)-ltailedlength<40)
                          noin = 0;
                          end
%                          =======
                   end
                   hom_left_max = modif_ho(1:max_left);
                   hom_right_max = modif_ho(end-max_right+1:end);
               else
                modif_ho = fullqw;
                   for wwi1 = 2:length(bsaw{1})-1
                         pause(0.1);
                         app.CheckBox_4.Enable = 'on';
                         app.CheckBox_5.Enable = 'on';
                         app.CheckBox_6.Enable = 'on';
                         app.CheckBox_7.Enable = 'on';
                         app.CheckBox_8.Enable = 'on';
                         app.CheckBox_9.Enable = 'on';
                         app.CheckBox_11.Enable = 'on';
                         app.CheckBox_12.Enable = 'on';
                         app.CheckBox_13.Enable = 'on';
                         app.CheckBox_14.Enable = 'on';
                         app.CheckBox_10.Enable = 'on';
                         app.CheckBox_15.Enable = 'on';
                         if bsaw{2}(wwi1) == 1
                             app.Panel_4.Visible = 'on';
                             app.Panel_5.Visible = 'off';
                             app.modchangew1 = 1;
                         else
                             app.Panel_4.Visible = 'off';
                             app.Panel_5.Visible = 'on';
                             app.modchangew1 = 2;
                         end
                         app.Label_5.Text = num2str(ltailedlength + 1);
                         app.Label_6.Text = num2str(ltailedlength + length(m_homol));
                         app.Label_7.Text = num2str(ltailedlength + length(m_homol) + rtailedlength);
                         if bsaw{1}(wwi1)>ltailedlength
                         app.vLabel.Position(1) = 143 +(bsaw{1}(wwi1)-ltailedlength-1)*(361-143)/(length(m_homol)-1);
                         else
                         app.vLabel.Position(1) = 143 +(bsaw{1}(wwi1)-ltailedlength-1)*(143-70)/ltailedlength;
                         end
                         app.BsaIposLabel.Position(1) = app.vLabel.Position(1)+1;
                         app.BsaIposLabel.Text = ['BsaI (' num2str(bsaw{1}(wwi1)) '>' num2str(bsaw{1}(wwi1)+5) ')'];
                         app.Label_22.Text = num2str(bsaw{1}(wwi1));
                         app.Label_23.Text = num2str(bsaw{1}(wwi1)+5);
                         app.GGTCTCLabel.Text = app.bsaI{bsaw{2}(wwi1)};
                         app.EditField.Value = ['5''...' fullqw(bsaw{1}(wwi1)-19:bsaw{1}(wwi1)-1)];
                         app.EditField_2.Value = [fullqw(bsaw{1}(wwi1)+6:bsaw{1}(wwi1)+24) '...3'''];
                         if bsaw{1}(wwi1)<ltailedlength
                         app.CheckBox_13.Enable = 'off';
                         app.CheckBox_7.Enable = 'off';
                         app.SomesilentmutationsLabel.Visible = 'on';
                         app.arenotavailablebecauseLabel.Visible = 'on';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'on';
                         app.linkersitepleaseselectLabel.Visible = 'on';
                         app.anothermutationorLabel.Visible = 'on';
                         app.manuallyalterthemoduleLabel.Visible = 'on';
                         app.sequenceLabel.Visible = 'on';
                         else
                         if bsaw{1}(wwi1)+5>ltailedlength+length(m_homol)+1
                         app.CheckBox_6.Enable = 'off';
                         app.CheckBox_12.Enable = 'off';
                         app.SomesilentmutationsLabel.Visible = 'on';
                         app.arenotavailablebecauseLabel.Visible = 'on';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'on';
                         app.linkersitepleaseselectLabel.Visible = 'on';
                         app.anothermutationorLabel.Visible = 'on';
                         app.manuallyalterthemoduleLabel.Visible = 'on';
                         app.sequenceLabel.Visible = 'on';
                         else
                         app.SomesilentmutationsLabel.Visible = 'off';
                         app.arenotavailablebecauseLabel.Visible = 'off';
                         app.theBsaIsiteoverlaptheLabel.Visible = 'off';
                         app.linkersitepleaseselectLabel.Visible = 'off';
                         app.anothermutationorLabel.Visible = 'off';
                         app.manuallyalterthemoduleLabel.Visible = 'off';
                         app.sequenceLabel.Visible = 'off';
                         end
                         end
                         if bsaw{1}(wwi1)+5>ltailedlength+length(m_homol)+2
                         app.CheckBox_15.Enable = 'off';
                         app.CheckBox_11.Enable = 'off';
                         app.CheckBox_9.Enable = 'off';
                         app.CheckBox_5.Enable = 'off';
                         end
                         if bsaw{1}(wwi1)<ltailedlength-1
                         app.CheckBox_14.Enable = 'off';
                         app.CheckBox_10.Enable = 'off';
                         app.CheckBox_8.Enable = 'off';
                         app.CheckBox_4.Enable = 'off';
                         end
                         app.Panel.Visible = 'on';
                         hhw = app.GGLabel;
                         uiwait(app.COSPLAY);
                         if ~ishandle(hhw)
                         return
                         end
                         modif_ho(bsaw{1}(wwi1)+app.positionchanw(app.modchangew2)) = app.modchangew3;
                   end
  l_tailed1w =modif_ho(1:round(length(modif_ho)/2)+10);
   r_tailed1w =modif_ho(round(length(modif_ho)/2)-9:end);
   r_tailed1w =fliplr(r_tailed1w);
  r_tailed1w=seqcomp(app,r_tailed1w);
  app.WaitplsLabel.Visible = 'off';
  app.logg2 = {'Forward tailed primer:' l_tailed1w '' 'Reverse tailed primer:' r_tailed1w '' 'Use the two primers without matrix !'};
  app.TextArea_3.Value = {'Forward tailed primer:' l_tailed1w '' 'Reverse tailed primer:' r_tailed1w '' 'Use the two primers without matrix !'}; 
        app.SaveButton_2.Enable = 'on' ;
            uialert(app.COSPLAY, 'The module sequence is short enough and can be generated by a primers dimer that alters the internal BsaI sites. Use the two primers without matrix !', 'Warning', 'Icon' , 'warning');
            app.CopyforwardprimerButton_2.Enable = 'on' ;
            app.CopyreverseprimerButton_2.Enable = 'on' ;
            app.ClearButton_2.Enable = 'on';
            app.forwardp2=l_tailed1w ;
            app.reversep2=r_tailed1w;
            app.GeneratetailedprimersButton.Enable = 'on' ;
          app.GeneratetailedprimersButton_2.Enable = 'on' ;
  return
               end
            end
%           ------------------------------------------------------------------------------------------------            
            hom_right_max = fliplr(hom_right_max);
            hom_right_max = seqcomp(app,hom_right_max);
            m_homol_re = fliplr(m_homol);
            m_homol_re = seqcomp(app,m_homol_re);
            m_homolengh = length(m_homol);
            ltm = [];
            rtm = [];
            lpenalty = [];
            rpenalty = [];
            linfo = [];
            rinfo = [];
            penalty_left_frw = seqidentity(app,m_homol,[l_tailed hom_left_max],[],[]);
            penalty_left_frw = penalty_left_frw(end-max_left+min_left:end);
            penalty_right_frw = seqidentity(app,m_homol_re,[r_tailed hom_right_max],[],[]);
            penalty_right_frw = penalty_right_frw(end-max_right+min_right:end);
            v21 = ltailedlength+1:ltailedlength+max_left;
            v11 = 1:max_left;
            penalty_left_re = seqidentity(app,m_homol_re,[l_tailed hom_left_max],v11,v21);
            penalty_left_re = penalty_left_re(ltailedlength+min_left:end);
            v21 = rtailedlength+1:rtailedlength+max_right;
            v11 = 1:max_right;
            penalty_right_re = seqidentity(app,m_homol,[r_tailed hom_right_max],v11,v21);
            penalty_right_re = penalty_right_re(rtailedlength+min_right:end);
            penalty_leftmat = max(penalty_left_frw,penalty_left_re);
            penalty_rightmat = max(penalty_right_frw,penalty_right_re);
            penalty_leftmat = (penalty_leftmat-app.StrIIMatmin);
            z1 = zeros(1,max_left-min_left+1);
            penalty_leftmat = max(penalty_leftmat,z1);
            penaltyleftmatwww = penalty_leftmat;
            penalty_leftmat = penalty_leftmat*app.StrIIMatweight;
            penalty_rightmat = (penalty_rightmat-app.StrIIMatmin);
            z1 = zeros(1,max_right-min_right+1);
            penalty_rightmat = max(penalty_rightmat,z1);
            penaltyrightmatwww = penalty_rightmat;
            penalty_rightmat = penalty_rightmat*app.StrIIMatweight;
            app.modul = {m_homol m_homol_re};
            for i=min_left:max_left
                v1 = 1:i;
                v2 = m_homolengh-i+1:m_homolengh;
                [penaltyg,info,tm1] = penaltyoligo(app,l_tailed,hom_left_max(1:i),hmodifl,smodifl,gcmodifl,v1,v2,[1 2]);
                lpenalty = [lpenalty penaltyg];
                linfo = [linfo;info];
                ltm = [ltm tm1];
            end
            for i=min_right:max_right
                v1 = 1:i;
                v2 = m_homolengh-i+1:m_homolengh;
                [penaltyg,info,tm1] = penaltyoligo(app,r_tailed,hom_right_max(1:i),hmodifr,smodifr,gcmodifr,v1,v2,[2 1]);
                rpenalty = [rpenalty penaltyg];
                rinfo = [rinfo;info];
                rtm = [rtm tm1];
            end
            penaltymin = inf;
            lolig = 0;
            rolig = 0;
            oligol_oligor_dimer = 0;
            oligol_oligor_3prime = 0;
            oligodeltm = 0;
            for i=min_left:max_left
           oligol_oligor = seqidentity(app,[l_tailed hom_left_max(1:i)],[r_tailed hom_right_max],[],[] );
           oligol_oligor = oligol_oligor(rtailedlength+min_right:end);
             for j=min_right:max_right
                 if i+j>m_homolengh
                 break;
                 end
             penaltyg = max(0,(oligol_oligor(j-min_right+1)-app.StrIImin))*app.StrIIweight;
             oligol_oligor3 = seqidentity3(app,[l_tailed hom_left_max(1:i)],[r_tailed hom_right_max(1:j)],2,[],[]);
             penaltyg = penaltyg+max(0,(oligol_oligor3-app.StrII3min))*app.StrII3weight;
             penaltyg = penaltyg+abs(ltm(i-min_left+1)-rtm(j-min_right+1))*app.deltaTmweight;
             penaltyg = penaltyg+lpenalty(i-min_left+1)+rpenalty(j-min_right+1)+penalty_leftmat(i-min_left+1)+penalty_rightmat(j-min_right+1);
               if penaltyg<penaltymin
                  lolig = i;
                  rolig = j;
                  penaltymin = penaltyg;
                  oligol_oligor_dimer = max(0,(oligol_oligor(j-min_right+1)-app.StrIImin));
                  oligol_oligor_3prime = max(0,(oligol_oligor3-app.StrII3min));
                  oligodeltm = abs(ltm(i-min_left+1)-rtm(j-min_right+1));
               end
             end
            end
            reprw = {};
            if oligodeltm<2
                reprw = {reprw{:} 'OK                '};
            else
            if oligodeltm<5
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if oligol_oligor_dimer==0
                reprw = {reprw{:} 'OK                '};
            else
            if oligol_oligor_dimer<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if oligol_oligor_3prime==0
                reprw = {reprw{:} 'OK                '};
            else
            if oligol_oligor_3prime<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if abs(ltm(lolig-min_left+1)-app.Tmopt)<=4
                reprw = {reprw{:} 'OK                '};
            else
            if abs(ltm(lolig-min_left+1)-app.Tmopt)<7
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if abs(rtm(rolig-min_right+1)-app.Tmopt)<=4
            reprw = {reprw{:} 'OK                '};
            else
            if abs(rtm(rolig-min_right+1)-app.Tmopt)<7
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if lolig<14
            reprw = {reprw{:} 'BAD             '};
            else
            if lolig<17
            reprw = {reprw{:} 'MEDIOCRE'};
            else
              if lolig<30
              reprw = {reprw{:} 'OK                '};
              else
                if lolig<38
                reprw = {reprw{:} 'MEDIOCRE'};
                else
                  reprw = {reprw{:} 'BAD             '};
                end
              end
            end
            end
            if rolig<14
            reprw = {reprw{:} 'BAD             '};
            else
            if rolig<17
            reprw = {reprw{:} 'MEDIOCRE'};
            else
              if rolig<30
              reprw = {reprw{:} 'OK                '};
              else
                if rolig<38
                reprw = {reprw{:} 'MEDIOCRE'};
                else
                  reprw = {reprw{:} 'BAD             '};
                end
              end
            end
            end
            if linfo(lolig-min_left+1,2)==0
                reprw = {reprw{:} 'OK                '};
            else
            if linfo(lolig-min_left+1,2)<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if rinfo(rolig-min_right+1,2)==0
                reprw = {reprw{:} 'OK                '};
            else
            if rinfo(rolig-min_right+1,2)<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if linfo(lolig-min_left+1,3)==0
                reprw = {reprw{:} 'OK                '};
            else
            if linfo(lolig-min_left+1,3)<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if rinfo(rolig-min_right+1,3)==0
                reprw = {reprw{:} 'OK                '};
            else
            if rinfo(rolig-min_right+1,3)<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if linfo(lolig-min_left+1,1)==0
                reprw = {reprw{:} 'OK                '};
            else
            if linfo(lolig-min_left+1,1)<=2
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if rinfo(rolig-min_right+1,1)==0
                reprw = {reprw{:} 'OK                '};
            else
            if rinfo(rolig-min_right+1,1)<=2
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if linfo(lolig-min_left+1,6)==0
                reprw = {reprw{:} 'OK                '};
            else
            if linfo(lolig-min_left+1,6)<=1
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if rinfo(rolig-min_right+1,6)==0
                reprw = {reprw{:} 'OK                '};
            else
            if rinfo(rolig-min_right+1,6)<=1
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if linfo(lolig-min_left+1,7)==0
                reprw = {reprw{:} 'OK                '};
            else
            if linfo(lolig-min_left+1,7)<=1
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if rinfo(rolig-min_right+1,7)==0
                reprw = {reprw{:} 'OK                '};
            else
            if rinfo(rolig-min_right+1,7)<=1
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if linfo(lolig-min_left+1,5)==0
                reprw = {reprw{:} 'OK                '};
            else
            if linfo(lolig-min_left+1,5)<=2
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
           if rinfo(rolig-min_right+1,5)==0
                reprw = {reprw{:} 'OK                '};
            else
            if rinfo(rolig-min_right+1,5)<=2
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
           end
            if penaltyleftmatwww(lolig-min_left+1)==0
                reprw = {reprw{:} 'OK                '};
            else
            if penaltyleftmatwww(lolig-min_left+1)<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if penaltyrightmatwww(rolig-min_right+1)==0
                reprw = {reprw{:} 'OK                '};
            else
            if penaltyrightmatwww(rolig-min_right+1)<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
             if linfo(lolig-min_left+1,4)==0
                reprw = {reprw{:} 'OK                '};
            else
            if linfo(lolig-min_left+1,4)<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            if rinfo(rolig-min_right+1,4)==0
                reprw = {reprw{:} 'OK                '};
            else
            if rinfo(rolig-min_right+1,4)<3
            reprw = {reprw{:} 'MEDIOCRE'};
            else
            reprw = {reprw{:} 'BAD             '};
            end
            end
            logg = sprintf(['Primers Tm difference\t\t\t\t\t' reprw{1} '\nPrimers cross complementarity\t\t\t' reprw{2} '\nPrimers 3'' cross complementarity   \t\t' reprw{3} ...
                '\n\nIndividual primers    \t\t\t\t\tForward\t\t\tReverse\n\nTm\t\t\t\t\t\t\t\t\t' reprw{4} '\t\t' reprw{5} '\nPrimer size\t\t\t\t\t\t\t' reprw{6} '\t\t' reprw{7} ...
                '\nSelf complementarity\t\t\t\t\t' reprw{8} '\t\t' reprw{9} '\n3'' self complementarity   \t\t\t\t' reprw{10} '\t\t' reprw{11} '\nG/C %%  \t\t\t\t\t\t\t\t' reprw{12} '\t\t' reprw{13} ...
                '\nG/C clamps\t\t\t\t\t\t\t' reprw{14} '\t\t' reprw{15} '\n3'' stability\t\t\t\t\t\t\t' reprw{16} '\t\t' reprw{17} '\nPoly-X \t\t\t\t\t\t\t\t' reprw{18} '\t\t' reprw{19} '\nTemplate ectopic complementarity \t\t' reprw{20} '\t\t' reprw{21} '\nTemplate 3'' ectopic complementarity     \t' reprw{22} '\t\t' reprw{23} ]);
            logg21 = ['Primers Tm difference\t\t\t\t' reprw{1} '\r\nPrimers cross complementarity\t\t\t' reprw{2} '\r\nPrimers 3'' cross complementarity\t\t' reprw{3} ...
                '\r\n\r\nIndividual primers\t\t\t\tForward\t\t\t\tReverse\r\n\r\nTm\t\t\t\t\t\t' reprw{4} '\t\t' reprw{5} '\r\nPrimer size\t\t\t\t\t' reprw{6} '\t\t' reprw{7} ...
                '\r\nSelf complementarity\t\t\t\t' reprw{8} '\t\t' reprw{9} '\r\n3'' self complementarity\t\t\t\t' reprw{10} '\t\t' reprw{11} '\r\nG/C %%\t\t\t\t\t\t' reprw{12} '\t\t' reprw{13} ...
                '\r\nG/C clamps\t\t\t\t\t' reprw{14} '\t\t' reprw{15} '\r\n3'' stability\t\t\t\t\t' reprw{16} '\t\t' reprw{17} '\r\nPoly-X\t\t\t\t\t\t' reprw{18} '\t\t' reprw{19} '\r\nTemplate ectopic complementarity\t\t' reprw{20} '\t\t' reprw{21} '\r\nTemplate 3'' ectopic complementarity\t\t' reprw{22} '\t\t' reprw{23} ];
            logg241 = strrep(logg21, 'MEDIOCRE', 'MEDIOCRE\t');
            app.logg2 = {['Forward tailed primer (Q5 pol Tm = ' num2str(ltm(lolig-min_left+1),'%.1f') '°C):'] [l_tailed hom_left_max(1:lolig)] '' ...
            ['Reverse tailed primer (Q5 pol Tm = ' num2str(rtm(rolig-min_right+1),'%.1f') '°C):'] [r_tailed hom_right_max(1:rolig)] '' 'FULL REPORT:' '' 'Primers pair' '' sprintf(logg241)};
            app.TextArea_3.Value = {['Forward tailed primer (Q5 pol Tm = ' num2str(ltm(lolig-min_left+1),'%.1f') '°C):'] [l_tailed hom_left_max(1:lolig)] '' ...
            ['Reverse tailed primer (Q5 pol Tm = ' num2str(rtm(rolig-min_right+1),'%.1f') '°C):'] [r_tailed hom_right_max(1:rolig)] '' 'FULL REPORT:' '' 'Primers pair' '' logg};
            if noin == 0
           uialert(app.COSPLAY,    'There is at least one BsaI site in the module sequence that is too far from both 5'' and 3'' ends and thus it can not be mutated by single PCR reaction. Please consider additional PCR reactions with internal primers. You can still use the external primers designed by this software.' , 'Warning', 'Icon','warning');
          end
            app.SaveButton_2.Enable = 'on' ;
            app.CopyforwardprimerButton_2.Enable = 'on' ;
            app.CopyreverseprimerButton_2.Enable = 'on' ;
            app.ClearButton_2.Enable = 'on';
            app.forwardp2=[l_tailed hom_left_max(1:lolig)];
            app.reversep2=[r_tailed hom_right_max(1:rolig)];
%           ================================================================================================
            end
            app.GeneratetailedprimersButton.Enable = 'on' ;
            app.WaitplsLabel.Visible = 'off';
            app.GeneratetailedprimersButton_2.Enable = 'on' ;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ClearButton.Enable = 'off' ;
                                    app.SaveButton.Enable = 'off' ;
            app.CopyforwardprimerButton.Enable = 'off' ;
            app.CopyreverseprimerButton.Enable = 'off' ;
            app.forwardp=[];
            app.reversep=[];
            app.TextArea_2.Value = '';
            app.TextArea_2.FontColor = [0 0 0];
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.ClearButton_2.Enable = 'off' ;
            app.SaveButton_2.Enable = 'off' ;
            app.CopyforwardprimerButton_2.Enable = 'off' ;
            app.CopyreverseprimerButton_2.Enable = 'off' ;
            app.forwardp2=[];
            app.reversep2=[];
            app.TextArea_3.Value = '';
            app.TextArea_3.FontColor = [0 0 0];
        end

        % Button pushed function: CopyforwardprimerButton
        function CopyforwardprimerButtonPushed(app, event)
            stop(app.myTimer);
            clipboard('copy',app.forwardp);
            app.CopiedLabel.Visible = 'on';            
            start(app.myTimer);
        end

        % Button pushed function: CopyreverseprimerButton
        function CopyreverseprimerButtonPushed(app, event)
            stop(app.myTimer2);
            clipboard('copy',app.reversep);
            app.CopiedLabel_2.Visible = 'on';
            start(app.myTimer2);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file_name, Path_name] = uiputfile({'*.txt'}, 'Save File');
            app.COSPLAY.Visible = 'off';
            app.COSPLAY.Visible = 'on';
            if ischar(file_name)
            Filen = fullfile(Path_name, file_name);
            fileid=fopen(Filen,'w');
            for i=1:length(app.TextArea_2.Value)
            fixedstr=strrep(app.TextArea_2.Value{i}, '%', '%%');
            fprintf(fileid,fixedstr);
            if i<length(app.TextArea_2.Value)
            fprintf(fileid,'\r\n');
            end
            end
            fclose(fileid);
            end
        end

        % Close request function: COSPLAY
        function COSPLAYCloseRequest(app, event)
            delete(app.myTimer);
            delete(app.myTimer2);
            delete(app.myTimer3);
            delete(app.myTimer4);
            delete(app.optionsbox);
            delete(app)  
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            [file_name, Path_name] = uiputfile({'*.txt'}, 'Save File');
            app.COSPLAY.Visible = 'off';
            app.COSPLAY.Visible = 'on';
            if ischar(file_name)
            Filen = fullfile(Path_name, file_name);
            fileid=fopen(Filen,'w');
            for i=1:length(app.logg2)
            fixedstr=strrep(app.logg2{i}, '%', '%%');
            fprintf(fileid,fixedstr);
            if i<length(app.logg2)
            fprintf(fileid,'\r\n');
            end
            end
            fclose(fileid);
            end
        end

        % Button pushed function: CopyforwardprimerButton_2
        function CopyforwardprimerButton_2Pushed(app, event)
            stop(app.myTimer3);
            clipboard('copy',app.forwardp2);
            app.CopiedLabel_3.Visible = 'on';
            start(app.myTimer3);
        end

        % Button pushed function: CopyreverseprimerButton_2
        function CopyreverseprimerButton_2Pushed(app, event)
            stop(app.myTimer4);
            clipboard('copy',app.reversep2);
            app.CopiedLabel_4.Visible = 'on';
            start(app.myTimer4);
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
          app.OptionsButton.Enable = 'off';
          weight = app.Tmopto;
          weight = [weight app.Tmweighto]; 
          weight = [weight app.Tailleweighto];
          weight = [weight app.StrIIweighto];
          weight = [weight app.StrII3weighto]; 
          weight = [weight app.StrIIMatweighto];
          weight = [weight app.StrII3Matweighto];
          weight = [weight app.Repeatsweighto]; 
          weight = [weight app.GCweighto];
          weight = [weight app.GC3weighto];
          weight = [weight app.Stability3weighto]; 
          weight = [weight app.deltaTmweighto];
          app.optionsbox = options_new(app,weight,app.ascIfseIo);
        end

        % Value changing function: ModulepositionSpinner_2
        function ModulepositionSpinner_2ValueChanging(app, event)
            Value = event.Value;
            if Value==3
            app.CheckBox_3.Visible = 'on'; 
            app.Fusedtomodule4Label_2.Visible = 'on';
            else
            app.CheckBox_3.Visible = 'off'; 
            app.Fusedtomodule4Label_2.Visible = 'off';
            end
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)
            value = app.CheckBox_12.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_15
        function CheckBox_15ValueChanged(app, event)
            value = app.CheckBox_15.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_14
        function CheckBox_14ValueChanged(app, event)
            value = app.CheckBox_14.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_13
        function CheckBox_13ValueChanged(app, event)
            value = app.CheckBox_13.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_11
        function CheckBox_11ValueChanged(app, event)
            value = app.CheckBox_11.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            if value == 1
            app.CheckBox_4.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            if value == 1
            app.CheckBox_12.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            app.OKButton.Enable = 'on';
            else
            app.OKButton.Enable = 'off';
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.OKButton.Enable ='off';
            if app.CheckBox_4.Value == 1
               app.modchangew2 = 4;
               app.modchangew3 = 'A';
            end
            if app.CheckBox_5.Value == 1
               app.modchangew2 = 5;
               app.modchangew3 = 'A';
            end
            if app.CheckBox_6.Value == 1
               app.modchangew2 = 6;
               app.modchangew3 = 'A';
            end
            if app.CheckBox_7.Value == 1
               app.modchangew2 = 1;
               app.modchangew3 = 'A';
            end
            if app.CheckBox_8.Value == 1
               app.modchangew2 = 2;
               app.modchangew3 = 'C';
            end
            if app.CheckBox_9.Value == 1
               app.modchangew2 = 3;
               app.modchangew3 = 'T';
            end
            if app.CheckBox_10.Value == 1
               app.modchangew2 = 4;
               app.modchangew3 = 'A';
            end
            if app.CheckBox_11.Value == 1
               app.modchangew2 = 5;
               app.modchangew3 = 'G';
            end
            if app.CheckBox_12.Value == 1
               app.modchangew2 = 6;
               app.modchangew3 = 'T';
            end
            if app.CheckBox_13.Value == 1
               app.modchangew2 = 1;
               app.modchangew3 = 'T';
            end
            if app.CheckBox_14.Value == 1
               app.modchangew2 = 2;
               app.modchangew3 = 'T';
            end
            if app.CheckBox_15.Value == 1
               app.modchangew2 = 3;
               app.modchangew3 = 'T';
            end
            app.Panel.Visible = 'off';
            app.CheckBox_4.Value = 0;
            app.CheckBox_12.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            app.CheckBox_8.Value = 0;
            app.CheckBox_9.Value = 0;
            app.CheckBox_11.Value = 0;
            app.CheckBox_13.Value = 0;
            app.CheckBox_14.Value = 0;
            app.CheckBox_10.Value = 0;
            app.CheckBox_15.Value = 0;
            uiresume(app.COSPLAY);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            uialert(app.COSPLAY, ['Use this tab of the COSPLAY software if you already have module homologous primers ' ...
            'and you just want to add the right tails in order to integrate a module into a carying plasmid. ' ...
            'To do so, please enter both the forward and reverse primer sequences in the 5''>3'' orientation. Next select the ' ...
            'corresponding position of the module in a final assembled vector. In case the module position is 3 and you want to fuse it to the module 4, please check "Fuse to module 4". Finally, click on the button "Generate tailed primers". ' ...
            'The whole primers sequences (tails + homologous primers) will be displayed as well as the corresponding Tm. ' ...
            'Note that this Tm is calculated for the Q5 Polymerase. For Taq Polymerase, the Tm should be 7°C lower.' ], 'Info', 'Icon' , 'info');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            uialert(app.COSPLAY, ['Use this tab of the COSPLAY software if you want to automatically design the entire primers ' ...
            '(tails + homologous sequences) required to integrate a module into a carying plasmid. ' ...
            'To do so, please enter the module sequence you want to integrate in the 5''>3'' orientation. Next select the ' ...
            'corresponding position of the module in a final assembled vector. If the module position is 1 and you want to use it for single integration, please check "Single integration". ' ...
            'In case the module position is 3 and you want to fuse it to the module 4, please check "Fuse to module 4". Finally, click on the button "Generate tailed primers" and wait few seconds. ' ...
            'The whole primers sequences (tails + homologous sequences) will be displayed as well as the corresponding Tm. ' ...
            'Note that this Tm is calculated for the Q5 Polymerase. For Taq Polymerase, the Tm should be 7°C lower. In addition, a full report of the primers pair quality will be displayed. Different criteria are used to optimize the primers. To change the weight of these criteria click on the button "Option".' ], 'Info', 'Icon' , 'info');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
           uialert(app.COSPLAY, 'The proposed substitutions correspond to silent mutations in different reading frames and directions. You can select only one substitution at the same time. If you want to induce other mutations you should manually alter the sequence before using the software. If you do so, please check that one of the automatically designed primers covers the mutation as well as at least 5 nt downstream. If it is not the case, please design your own homologous primers and use the tab "Add tails to homologous primers". Note that if you manually alter the sequence you should lower the primer Tm with 4°C per point mutation.' , 'Info', 'Icon', 'info'); 
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.concepts <4
            app.concepts = app.concepts + 1;
            app.BsaIisusedtogeneratespecificLabel_76.Text =['-' num2str(app.concepts) '/4-' ];
            switch app.concepts
                case 2
                     app.Button_6.Enable = 'on' ;
                     app.BsaIisusedtogeneratespecificLabel.Visible = 'off' ;
                     app.BsaIisusedtogeneratespecificLabel_2.Visible = 'off' ;
                     app.BsaIisusedtogeneratespecificLabel_3.Visible = 'off' ;
                     app.BsaIisusedtogeneratespecificLabel_4.Visible = 'off' ;
                     app.BsaIisusedtogeneratespecificLabel_5.Visible = 'off' ;
                     app.BsaIisusedtogeneratespecificLabel_6.Visible = 'off' ;
                     app.BsaIisusedtogeneratespecificLabel_7.Visible = 'off' ;
                     app.ModularplasmidassemblyLabel.Text ='New modules generation' ;
                             wgg = imread('wwg.png' );
%           [x,y,z] =size(wgg)
%           app.UIAxes.Position(3)=y*1.1;
%           app.UIAxes.Position(4)=x*1.1;
            wI = imshow(wgg, 'Parent',app.UIAxes, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]) ;
          app.UIAxes.XLim = [0 wI.XData(2)];
          app.UIAxes.YLim = [0 wI.YData(2)];
          app.Label_42.Visible = 'on' ;
          app.Label_43.Visible = 'on' ;
          app.Label_44.Visible = 'on' ;
          app.Label_45.Visible = 'on' ;
          app.Label_46.Visible = 'on' ;
          app.Label_47.Visible = 'on' ;
          app.Label_48.Visible = 'on' ;
                case 3
%                      app.Button_5.Enable = 'off' ;
          app.Label_42.Visible = 'off' ;
          app.Label_43.Visible = 'off' ;
          app.Label_44.Visible = 'off' ;
          app.Label_45.Visible = 'off' ;
          app.Label_46.Visible = 'off' ;
          app.Label_47.Visible = 'off' ;
          app.Label_48.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_69.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_70.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_71.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_72.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_74.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_75.Visible = 'on' ;
          app.ModularplasmidassemblyLabel.Text ='Modules mega-primers' ;
           wgg = imread('ggg.png' );
%           [x,y,z] =size(wgg)
%           app.UIAxes.Position(3)=y*1.1;
%           app.UIAxes.Position(4)=x*1.1;
            wI = imshow(wgg, 'Parent',app.UIAxes, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]) ;
          app.UIAxes.XLim = [0 wI.XData(2)];
          app.UIAxes.YLim = [0 wI.YData(2)];
                case 4
                    app.Button_5.Enable = 'off' ;
                    app.BsaIisusedtogeneratespecificLabel_69.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_70.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_71.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_72.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_74.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_75.Visible = 'off' ;
                    app.ModularplasmidassemblyLabel.Text ='Single integration' ;
                    app.BsaIisusedtogeneratespecificLabel_77.Visible = 'on' ;
                    app.BsaIisusedtogeneratespecificLabel_78.Visible = 'on' ;
                    app.BsaIisusedtogeneratespecificLabel_79.Visible = 'on' ;
                    wgg = imread('wwwwggg.png' );
%           [x,y,z] =size(wgg)
%           app.UIAxes.Position(3)=y*1.1;
%           app.UIAxes.Position(4)=x*1.1;
            wI = imshow(wgg, 'Parent',app.UIAxes, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]) ;
          app.UIAxes.XLim = [0 wI.XData(2)];
          app.UIAxes.YLim = [0 wI.YData(2)];
            end
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.concepts >1
            app.concepts = app.concepts - 1;
            app.BsaIisusedtogeneratespecificLabel_76.Text =['-' num2str(app.concepts) '/4-' ];
            switch app.concepts
                case 1
                     app.Button_6.Enable = 'off' ;
                                          app.BsaIisusedtogeneratespecificLabel.Visible = 'on' ;
                     app.BsaIisusedtogeneratespecificLabel_2.Visible = 'on' ;
                     app.BsaIisusedtogeneratespecificLabel_3.Visible = 'on' ;
                     app.BsaIisusedtogeneratespecificLabel_4.Visible = 'on' ;
                     app.BsaIisusedtogeneratespecificLabel_5.Visible = 'on' ;
                     app.BsaIisusedtogeneratespecificLabel_6.Visible = 'on' ;
                     app.BsaIisusedtogeneratespecificLabel_7.Visible = 'on' ;
                     app.ModularplasmidassemblyLabel.Text ='Modular plasmid assembly' ;
                             wgg = imread('wgg.png' );
%           [x,y,z] =size(wgg)
%           app.UIAxes.Position(3)=y*1.1;
%           app.UIAxes.Position(4)=x*1.1;
            wI = imshow(wgg, 'Parent',app.UIAxes, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]) ;
          app.UIAxes.XLim = [0 wI.XData(2)];
          app.UIAxes.YLim = [0 wI.YData(2)];
          app.Label_42.Visible = 'off' ;
          app.Label_43.Visible = 'off' ;
          app.Label_44.Visible = 'off' ;
          app.Label_45.Visible = 'off' ;
          app.Label_46.Visible = 'off' ;
          app.Label_47.Visible = 'off' ;
          app.Label_48.Visible = 'off' ;
                case 2
%                      app.Button_5.Enable = 'on' ;
           app.BsaIisusedtogeneratespecificLabel_69.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_70.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_71.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_72.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_74.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_75.Visible = 'off' ;
          app.ModularplasmidassemblyLabel.Text ='New modules generation' ;
                             wgg = imread('wwg.png' );
%           [x,y,z] =size(wgg)
%           app.UIAxes.Position(3)=y*1.1;
%           app.UIAxes.Position(4)=x*1.1;
            wI = imshow(wgg, 'Parent',app.UIAxes, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]) ;
          app.UIAxes.XLim = [0 wI.XData(2)];
          app.UIAxes.YLim = [0 wI.YData(2)];
          app.Label_42.Visible = 'on' ;
          app.Label_43.Visible = 'on' ;
          app.Label_44.Visible = 'on' ;
          app.Label_45.Visible = 'on' ;
          app.Label_46.Visible = 'on' ;
          app.Label_47.Visible = 'on' ;
          app.Label_48.Visible = 'on' ;
                case 3
                    app.Button_5.Enable = 'on' ;
                    app.BsaIisusedtogeneratespecificLabel_77.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_78.Visible = 'off' ;
          app.BsaIisusedtogeneratespecificLabel_79.Visible = 'off' ;
                    app.BsaIisusedtogeneratespecificLabel_69.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_70.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_71.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_72.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_74.Visible = 'on' ;
          app.BsaIisusedtogeneratespecificLabel_75.Visible = 'on' ;
          app.ModularplasmidassemblyLabel.Text ='Modules mega-primers' ;
           wgg = imread('ggg.png' );
%           [x,y,z] =size(wgg)
%           app.UIAxes.Position(3)=y*1.1;
%           app.UIAxes.Position(4)=x*1.1;
            wI = imshow(wgg, 'Parent',app.UIAxes, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]) ;
          app.UIAxes.XLim = [0 wI.XData(2)];
          app.UIAxes.YLim = [0 wI.YData(2)];
            end
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COSPLAY = uifigure;
            app.COSPLAY.Name = 'COSPLAY primers design';
            app.COSPLAY.CloseRequestFcn = createCallbackFcn(app, @COSPLAYCloseRequest, true);
            app.TabGroup = uitabgroup(app.COSPLAY);
            app.AddtailstohomologousprimersTab = uitab(app.TabGroup);
            app.AddtailstohomologousprimersTab.Title = 'Add tails to homologous primers';
            app.GeneratetailedprimersButton_2 = uibutton(app.AddtailstohomologousprimersTab, 'push');
            app.GeneratetailedprimersButton_2.ButtonPushedFcn = createCallbackFcn(app, @GeneratetailedprimersButton_2Pushed, true);
            app.GeneratetailedprimersButton_2.Text = 'Generate tailed primers';
            app.ModulepositionSpinner_2Label = uilabel(app.AddtailstohomologousprimersTab);
            app.ModulepositionSpinner_2Label.Text = 'Module position';
            app.ModulepositionSpinner_2 = uispinner(app.AddtailstohomologousprimersTab);
            app.ModulepositionSpinner_2.ValueChangingFcn = createCallbackFcn(app, @ModulepositionSpinner_2ValueChanging, true);
            app.ModulepositionSpinner_2.Limits = [1 6];
            app.ModulepositionSpinner_2.Value = 1;
            app.Forwardhomologoussequence53Label = uilabel(app.AddtailstohomologousprimersTab);
            app.Forwardhomologoussequence53Label.Text = 'Forward homologous sequence (5''→ 3'')';
            app.Forwardhomologoussequence53EditField = uieditfield(app.AddtailstohomologousprimersTab, 'text');
            app.Forwardhomologoussequence53EditField.ValueChangedFcn = createCallbackFcn(app, @Forwardhomologoussequence53EditFieldValueChanged, true);
            app.Reversehomologoussequence53Label = uilabel(app.AddtailstohomologousprimersTab);
            app.Reversehomologoussequence53Label.Text = 'Reverse homologous sequence (5''→ 3'')';
            app.Reversehomologoussequence53EditField = uieditfield(app.AddtailstohomologousprimersTab, 'text');
            app.Reversehomologoussequence53EditField.ValueChangedFcn = createCallbackFcn(app, @Reversehomologoussequence53EditFieldValueChanged, true);
            app.TextArea_2 = uitextarea(app.AddtailstohomologousprimersTab);
            app.TextArea_2.Editable = 'off';
            app.Tailedprimers53Label = uilabel(app.AddtailstohomologousprimersTab);
            app.Tailedprimers53Label.Text = 'Tailed primers (5''→ 3'')';
            app.ClearButton = uibutton(app.AddtailstohomologousprimersTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.CopyforwardprimerButton = uibutton(app.AddtailstohomologousprimersTab, 'push');
            app.CopyforwardprimerButton.ButtonPushedFcn = createCallbackFcn(app, @CopyforwardprimerButtonPushed, true);
            app.CopyforwardprimerButton.Enable = 'off';
            app.CopyforwardprimerButton.Text = 'Copy forward primer';
            app.CopyreverseprimerButton = uibutton(app.AddtailstohomologousprimersTab, 'push');
            app.CopyreverseprimerButton.ButtonPushedFcn = createCallbackFcn(app, @CopyreverseprimerButtonPushed, true);
            app.CopyreverseprimerButton.Enable = 'off';
            app.CopyreverseprimerButton.Text = 'Copy reverse primer';
            app.SaveButton = uibutton(app.AddtailstohomologousprimersTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'www.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.CopiedLabel = uilabel(app.AddtailstohomologousprimersTab);
            app.CopiedLabel.Visible = 'off';
            app.CopiedLabel.Text = 'Copied';
            app.CopiedLabel_2 = uilabel(app.AddtailstohomologousprimersTab);
            app.CopiedLabel_2.Visible = 'off';
            app.CopiedLabel_2.Text = 'Copied';
            app.CheckBox_3 = uicheckbox(app.AddtailstohomologousprimersTab);
            app.CheckBox_3.Visible = 'off';
            app.CheckBox_3.Text = '';
            app.Fusedtomodule4Label_2 = uilabel(app.AddtailstohomologousprimersTab);
            app.Fusedtomodule4Label_2.Visible = 'off';
            app.Fusedtomodule4Label_2.Text = 'Fused to module 4';
            app.Button_3 = uibutton(app.AddtailstohomologousprimersTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.PUC57BsaIhomologyLabel_2 = uilabel(app.AddtailstohomologousprimersTab);
            app.PUC57BsaIhomologyLabel_2.Text = 'PUC57-BsaI-homology';
            app.homologyBsaIPUC57Label_2 = uilabel(app.AddtailstohomologousprimersTab);
            app.homologyBsaIPUC57Label_2.Text = 'homology-BsaI-PUC57';
            app.tailedprimer3Label_2 = uilabel(app.AddtailstohomologousprimersTab);
            app.tailedprimer3Label_2.Text = '5''      tailed primer      3''';
            app.tailedprimer5Label_2 = uilabel(app.AddtailstohomologousprimersTab);
            app.tailedprimer5Label_2.Text = '3''      tailed primer      5''';
            app.moduleLabel_4 = uilabel(app.AddtailstohomologousprimersTab);
            app.moduleLabel_4.Text = 'module';
            app.homologyLabel_4 = uilabel(app.AddtailstohomologousprimersTab);
            app.homologyLabel_4.Text = 'homology';
            app.homologyLabel_5 = uilabel(app.AddtailstohomologousprimersTab);
            app.homologyLabel_5.Text = 'homology';
            app.Label_38 = uilabel(app.AddtailstohomologousprimersTab);
            app.Label_38.Text = '→';
            app.Label_39 = uilabel(app.AddtailstohomologousprimersTab);
            app.Label_39.Text = '←';
            app.DesignentireprimersTab = uitab(app.TabGroup);
            app.DesignentireprimersTab.Title = 'Design entire primers ';
            app.TextArea = uitextarea(app.DesignentireprimersTab);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Modulesequence53Label = uilabel(app.DesignentireprimersTab);
            app.Modulesequence53Label.Text = 'Module sequence (5''→ 3'')';
            app.ModulepositionSpinnerLabel = uilabel(app.DesignentireprimersTab);
            app.ModulepositionSpinnerLabel.Text = 'Module position';
            app.ModulepositionSpinner = uispinner(app.DesignentireprimersTab);
            app.ModulepositionSpinner.ValueChangingFcn = createCallbackFcn(app, @ModulepositionSpinnerValueChanging, true);
            app.ModulepositionSpinner.Limits = [1 6];
            app.ModulepositionSpinner.Value = 1;
            app.GeneratetailedprimersButton = uibutton(app.DesignentireprimersTab, 'push');
            app.GeneratetailedprimersButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratetailedprimersButtonPushed, true);
            app.GeneratetailedprimersButton.Text = 'Generate tailed primers';
            app.OptionsButton = uibutton(app.DesignentireprimersTab, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Text = 'Options';
            app.CheckBox = uicheckbox(app.DesignentireprimersTab);
            app.CheckBox.Text = '';
            app.SingleintegrationLabel = uilabel(app.DesignentireprimersTab);
            app.SingleintegrationLabel.Text = 'Single integration';
            app.CheckBox_2 = uicheckbox(app.DesignentireprimersTab);
            app.CheckBox_2.Visible = 'off';
            app.CheckBox_2.Text = '';
            app.Fusedtomodule4Label = uilabel(app.DesignentireprimersTab);
            app.Fusedtomodule4Label.Visible = 'off';
            app.Fusedtomodule4Label.Text = 'Fused to module 4';
            app.TextArea_3 = uitextarea(app.DesignentireprimersTab);
            app.TextArea_3.Editable = 'off';
            app.Tailedprimers53Label_2 = uilabel(app.DesignentireprimersTab);
            app.Tailedprimers53Label_2.Text = 'Tailed primers (5''→ 3'')';
            app.ClearButton_2 = uibutton(app.DesignentireprimersTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Enable = 'off';
            app.ClearButton_2.Text = 'Clear';
            app.CopyforwardprimerButton_2 = uibutton(app.DesignentireprimersTab, 'push');
            app.CopyforwardprimerButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopyforwardprimerButton_2Pushed, true);
            app.CopyforwardprimerButton_2.Enable = 'off';
            app.CopyforwardprimerButton_2.Text = 'Copy forward primer';
            app.CopyreverseprimerButton_2 = uibutton(app.DesignentireprimersTab, 'push');
            app.CopyreverseprimerButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopyreverseprimerButton_2Pushed, true);
            app.CopyreverseprimerButton_2.Enable = 'off';
            app.CopyreverseprimerButton_2.Text = 'Copy reverse primer';
            app.SaveButton_2 = uibutton(app.DesignentireprimersTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Icon = 'www.png';
            app.SaveButton_2.Enable = 'off';
            app.SaveButton_2.Text = 'Save';
            app.CopiedLabel_3 = uilabel(app.DesignentireprimersTab);
            app.CopiedLabel_3.Visible = 'off';
            app.CopiedLabel_3.Text = 'Copied';
            app.CopiedLabel_4 = uilabel(app.DesignentireprimersTab);
            app.CopiedLabel_4.Visible = 'off';
            app.CopiedLabel_4.Text = 'Copied';
            app.WaitplsLabel = uilabel(app.DesignentireprimersTab);
            app.WaitplsLabel.Visible = 'off';
            app.WaitplsLabel.Text = 'Wait pls ...';
            app.Button = uibutton(app.DesignentireprimersTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.PUC57BsaIhomologyLabel = uilabel(app.DesignentireprimersTab);
            app.PUC57BsaIhomologyLabel.Text = 'PUC57-BsaI-homology';
            app.homologyBsaIPUC57Label = uilabel(app.DesignentireprimersTab);
            app.homologyBsaIPUC57Label.Text = 'homology-BsaI-PUC57';
            app.tailedprimer3Label = uilabel(app.DesignentireprimersTab);
            app.tailedprimer3Label.Text = '5''      tailed primer      3''';
            app.tailedprimer5Label = uilabel(app.DesignentireprimersTab);
            app.tailedprimer5Label.Text = '3''      tailed primer      5''';
            app.moduleLabel_3 = uilabel(app.DesignentireprimersTab);
            app.moduleLabel_3.Text = 'module';
            app.Label_40 = uilabel(app.DesignentireprimersTab);
            app.Label_40.Text = '→';
            app.Label_41 = uilabel(app.DesignentireprimersTab);
            app.Label_41.Text = '←';
            app.ConceptsTab = uitab(app.TabGroup);
            app.ConceptsTab.Title = 'Concepts';
            app.UIAxes = uiaxes(app.ConceptsTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BsaIisusedtogeneratespecificLabel = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel.Text = 'BsaI is used to generate specific cohesive ends which ensure one step assembly of 6 modules. ';
            app.BsaIisusedtogeneratespecificLabel_2 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_2.Text = 'A BsaI particularity is that the recognition site is different from the cutting site :';
            app.BsaIisusedtogeneratespecificLabel_3 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_3.Text = 'GGTCTC';
            app.BsaIisusedtogeneratespecificLabel_4 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_4.Text = 'N';
            app.BsaIisusedtogeneratespecificLabel_5 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_5.Text = 'NNNN';
            app.BsaIisusedtogeneratespecificLabel_6 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_6.Text = 'recognition';
            app.BsaIisusedtogeneratespecificLabel_7 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_7.Text = 'cutting';
            app.Button_5 = uibutton(app.ConceptsTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '>';
            app.Button_6 = uibutton(app.ConceptsTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '<';
            app.Label_42 = uilabel(app.ConceptsTab);
            app.Label_42.Visible = 'off';
            app.Label_42.Text = 'To generate a new module plasmid, the module sequence is first amplified using a pair of tailed primers';
            app.Label_43 = uilabel(app.ConceptsTab);
            app.Label_43.Visible = 'off';
            app.Label_43.Text = 'containing specific BsaI sites, PUC-57 homologous regions (R) and module homologous regions (p). ';
            app.Label_44 = uilabel(app.ConceptsTab);
            app.Label_44.Visible = 'off';
            app.Label_44.Text = 'The product of the PCR and a PUC57 plasmid are then';
            app.Label_45 = uilabel(app.ConceptsTab);
            app.Label_45.Visible = 'off';
            app.Label_45.Text = 'used during a second PCR as a mega-primer and matrix';
            app.Label_46 = uilabel(app.ConceptsTab);
            app.Label_46.Visible = 'off';
            app.Label_46.Text = 'respectively (MEGAWHOP). The module sequence will ';
            app.Label_47 = uilabel(app.ConceptsTab);
            app.Label_47.Visible = 'off';
            app.Label_47.Text = 'be integrated into the PUC57 plasmid by homologous';
            app.Label_48 = uilabel(app.ConceptsTab);
            app.Label_48.Visible = 'off';
            app.Label_48.Text = 'recombination driven by the R regions.';
            app.BsaIisusedtogeneratespecificLabel_69 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_69.Visible = 'off';
            app.BsaIisusedtogeneratespecificLabel_69.Text = ': 5'' CGAATGCATCTAGATATCGGATCCC 3''';
            app.BsaIisusedtogeneratespecificLabel_70 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_70.Visible = 'off';
            app.BsaIisusedtogeneratespecificLabel_70.Text = 'PUC57-R1';
            app.BsaIisusedtogeneratespecificLabel_71 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_71.Visible = 'off';
            app.BsaIisusedtogeneratespecificLabel_71.Text = 'PUC57-R2';
            app.BsaIisusedtogeneratespecificLabel_72 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_72.Visible = 'off';
            app.BsaIisusedtogeneratespecificLabel_72.Text = ': 5'' GCAGGCCTCTGCAGTCGACGGGCCC 3''';
            app.BsaIisusedtogeneratespecificLabel_74 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_74.Visible = 'off';
            app.BsaIisusedtogeneratespecificLabel_74.Text = 'Module specific tails containing BsaI';
            app.BsaIisusedtogeneratespecificLabel_75 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_75.Visible = 'off';
            app.BsaIisusedtogeneratespecificLabel_75.Text = 'sites and PUC57 homologous regions. ';
            app.BsaIisusedtogeneratespecificLabel_76 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_76.Text = '-1/4-';
            app.ModularplasmidassemblyLabel = uilabel(app.ConceptsTab);
            app.ModularplasmidassemblyLabel.Text = 'Modular plasmid assembly';
            app.BsaIisusedtogeneratespecificLabel_77 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_77.Visible = 'off';
            app.BsaIisusedtogeneratespecificLabel_77.Text = 'Single integration is achieved by inverting 5'' and 3'' regions of the genomic target locus.';
            app.BsaIisusedtogeneratespecificLabel_78 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_78.Visible = 'off';
            app.BsaIisusedtogeneratespecificLabel_78.Text = 'Classical multi-copies integration';
            app.BsaIisusedtogeneratespecificLabel_79 = uilabel(app.ConceptsTab);
            app.BsaIisusedtogeneratespecificLabel_79.Visible = 'off';
            app.BsaIisusedtogeneratespecificLabel_79.Text = 'Single integration (inverted regions)';
            app.GCGTLabel_2 = uilabel(app.ConceptsTab);
            app.GCGTLabel_2.Text = 'GCGT';
            app.Panel = uipanel(app.COSPLAY);
            app.Panel.Visible = 'off';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_3 = uipanel(app.Panel_2);
            app.GGTCTCLabel = uilabel(app.Panel_3);
            app.GGTCTCLabel.Text = 'GGTCTC';
            app.LefttailLabel = uilabel(app.Panel_3);
            app.LefttailLabel.Text = 'Left tail';
            app.RighttailLabel = uilabel(app.Panel_3);
            app.RighttailLabel.Text = 'Right tail';
            app.ModuleLabel = uilabel(app.Panel_3);
            app.ModuleLabel.Text = 'Module';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '0';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '0';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '0';
            app.vLabel = uilabel(app.Panel_3);
            app.vLabel.Text = 'v';
            app.BsaIposLabel = uilabel(app.Panel_3);
            app.BsaIposLabel.Text = 'BsaI pos :';
            app.EditField = uieditfield(app.Panel_3, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '5''...GGGGGGGGGGGGGGGGGGG';
            app.EditField_2 = uieditfield(app.Panel_3, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'TTTTTTTTTTTTTTTTTTT...3''';
            app.Panel_4 = uipanel(app.Panel_3);
            app.GGTCTCLabel_2 = uilabel(app.Panel_4);
            app.GGTCTCLabel_2.Text = 'G G T C T C';
            app.GGLabel = uilabel(app.Panel_4);
            app.GGLabel.Text = 'G G ';
            app.GTLabel = uilabel(app.Panel_4);
            app.GTLabel.Text = 'G T ';
            app.TCLabel = uilabel(app.Panel_4);
            app.TCLabel.Text = 'T C ';
            app.ALabel = uilabel(app.Panel_4);
            app.ALabel.Text = 'A';
            app.ALabel_2 = uilabel(app.Panel_4);
            app.ALabel_2.Text = 'A';
            app.ALabel_3 = uilabel(app.Panel_4);
            app.ALabel_3.Text = 'A';
            app.CheckBox_4 = uicheckbox(app.Panel_4);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.Panel_4);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.Panel_4);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.AGLabel = uilabel(app.Panel_4);
            app.AGLabel.Text = 'A G';
            app.GALabel = uilabel(app.Panel_4);
            app.GALabel.Text = 'G A';
            app.AGLabel_2 = uilabel(app.Panel_4);
            app.AGLabel_2.Text = 'A G ';
            app.TLabel = uilabel(app.Panel_4);
            app.TLabel.Text = 'T';
            app.GLabel = uilabel(app.Panel_4);
            app.GLabel.Text = 'G';
            app.ALabel_4 = uilabel(app.Panel_4);
            app.ALabel_4.Text = 'A';
            app.CheckBox_7 = uicheckbox(app.Panel_4);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.Panel_4);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.Panel_4);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '';
            app.CCAGAGLabel = uilabel(app.Panel_4);
            app.CCAGAGLabel.Text = 'C C A G A G';
            app.Label_8 = uilabel(app.Panel_4);
            app.Label_8.Text = '5''                          3''  ';
            app.Label_9 = uilabel(app.Panel_4);
            app.Label_9.Text = '3''                          5''  ';
            app.Label_10 = uilabel(app.Panel_4);
            app.Label_10.Text = '1.';
            app.Label_11 = uilabel(app.Panel_4);
            app.Label_11.Text = '2.';
            app.Label_12 = uilabel(app.Panel_4);
            app.Label_12.Text = '3.';
            app.Label_13 = uilabel(app.Panel_4);
            app.Label_13.Text = '4.';
            app.Label_14 = uilabel(app.Panel_4);
            app.Label_14.Text = '5.';
            app.Label_15 = uilabel(app.Panel_4);
            app.Label_15.Text = '6.';
            app.Label_16 = uilabel(app.Panel_4);
            app.Label_16.Text = '→';
            app.Label_17 = uilabel(app.Panel_4);
            app.Label_17.Text = '→';
            app.Label_18 = uilabel(app.Panel_4);
            app.Label_18.Text = '→';
            app.Label_19 = uilabel(app.Panel_4);
            app.Label_19.Text = '←';
            app.Label_20 = uilabel(app.Panel_4);
            app.Label_20.Text = '←';
            app.Label_21 = uilabel(app.Panel_4);
            app.Label_21.Text = '←';
            app.ILabel_19 = uilabel(app.Panel_4);
            app.ILabel_19.Text = 'I';
            app.ILabel_20 = uilabel(app.Panel_4);
            app.ILabel_20.Text = 'I';
            app.ILabel_21 = uilabel(app.Panel_4);
            app.ILabel_21.Text = 'I';
            app.ILabel_22 = uilabel(app.Panel_4);
            app.ILabel_22.Text = 'I';
            app.ILabel_23 = uilabel(app.Panel_4);
            app.ILabel_23.Text = 'I';
            app.ILabel_24 = uilabel(app.Panel_4);
            app.ILabel_24.Text = 'I';
            app.ILabel_25 = uilabel(app.Panel_4);
            app.ILabel_25.Text = 'I';
            app.ILabel_26 = uilabel(app.Panel_4);
            app.ILabel_26.Text = 'I';
            app.ILabel_27 = uilabel(app.Panel_4);
            app.ILabel_27.Text = 'I';
            app.ILabel_28 = uilabel(app.Panel_4);
            app.ILabel_28.Text = 'I';
            app.ILabel_29 = uilabel(app.Panel_4);
            app.ILabel_29.Text = 'I';
            app.ILabel_30 = uilabel(app.Panel_4);
            app.ILabel_30.Text = 'I';
            app.ILabel_31 = uilabel(app.Panel_4);
            app.ILabel_31.Text = 'I';
            app.ILabel_32 = uilabel(app.Panel_4);
            app.ILabel_32.Text = 'I';
            app.ILabel_33 = uilabel(app.Panel_4);
            app.ILabel_33.Text = 'I';
            app.ILabel_34 = uilabel(app.Panel_4);
            app.ILabel_34.Text = 'I';
            app.ILabel_35 = uilabel(app.Panel_4);
            app.ILabel_35.Text = 'I';
            app.ILabel_36 = uilabel(app.Panel_4);
            app.ILabel_36.Text = 'I';
            app.OKButton = uibutton(app.Panel_3, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.SelectasilentpointLabel = uilabel(app.Panel_3);
            app.SelectasilentpointLabel.Text = 'Select a silent point';
            app.mutationinthecorrectLabel = uilabel(app.Panel_3);
            app.mutationinthecorrectLabel.Text = 'mutation in the correct';
            app.readingframeLabel = uilabel(app.Panel_3);
            app.readingframeLabel.Text = 'reading frame !';
            app.SilentpointmutationsLabel = uilabel(app.Panel_3);
            app.SilentpointmutationsLabel.Text = 'Silent point mutations';
            app.Label_22 = uilabel(app.Panel_3);
            app.Label_22.Text = '0';
            app.Label_23 = uilabel(app.Panel_3);
            app.Label_23.Text = '0';
            app.BsaILabel = uilabel(app.Panel_3);
            app.BsaILabel.Text = 'BsaI';
            app.SomesilentmutationsLabel = uilabel(app.Panel_3);
            app.SomesilentmutationsLabel.Text = 'Some silent mutations';
            app.arenotavailablebecauseLabel = uilabel(app.Panel_3);
            app.arenotavailablebecauseLabel.Text = 'are not available because';
            app.theBsaIsiteoverlaptheLabel = uilabel(app.Panel_3);
            app.theBsaIsiteoverlaptheLabel.Text = 'the BsaI site overlap the';
            app.linkersitepleaseselectLabel = uilabel(app.Panel_3);
            app.linkersitepleaseselectLabel.Text = 'linker site, please select';
            app.anothermutationorLabel = uilabel(app.Panel_3);
            app.anothermutationorLabel.Text = 'another mutation or';
            app.manuallyalterthemoduleLabel = uilabel(app.Panel_3);
            app.manuallyalterthemoduleLabel.Text = 'manually alter the module';
            app.sequenceLabel = uilabel(app.Panel_3);
            app.sequenceLabel.Text = 'sequence !';
            app.Panel_5 = uipanel(app.Panel_3);
            app.GAGACCLabel = uilabel(app.Panel_5);
            app.GAGACCLabel.Text = 'G A G A C C';
            app.GALabel_2 = uilabel(app.Panel_5);
            app.GALabel_2.Text = 'G A ';
            app.AGLabel_3 = uilabel(app.Panel_5);
            app.AGLabel_3.Text = 'A G ';
            app.GALabel_3 = uilabel(app.Panel_5);
            app.GALabel_3.Text = 'G A';
            app.ALabel_5 = uilabel(app.Panel_5);
            app.ALabel_5.Text = 'A';
            app.GLabel_4 = uilabel(app.Panel_5);
            app.GLabel_4.Text = 'G';
            app.TLabel_2 = uilabel(app.Panel_5);
            app.TLabel_2.Text = 'T';
            app.CheckBox_10 = uicheckbox(app.Panel_5);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '';
            app.CheckBox_11 = uicheckbox(app.Panel_5);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBox_11ValueChanged, true);
            app.CheckBox_11.Text = '';
            app.CheckBox_12 = uicheckbox(app.Panel_5);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Text = '';
            app.CTLabel = uilabel(app.Panel_5);
            app.CTLabel.Text = 'C T';
            app.TGLabel = uilabel(app.Panel_5);
            app.TGLabel.Text = 'T G';
            app.GGLabel_2 = uilabel(app.Panel_5);
            app.GGLabel_2.Text = 'G G';
            app.ALabel_6 = uilabel(app.Panel_5);
            app.ALabel_6.Text = 'A';
            app.ALabel_7 = uilabel(app.Panel_5);
            app.ALabel_7.Text = 'A';
            app.ALabel_8 = uilabel(app.Panel_5);
            app.ALabel_8.Text = 'A';
            app.CheckBox_13 = uicheckbox(app.Panel_5);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBox_13ValueChanged, true);
            app.CheckBox_13.Text = '';
            app.CheckBox_14 = uicheckbox(app.Panel_5);
            app.CheckBox_14.ValueChangedFcn = createCallbackFcn(app, @CheckBox_14ValueChanged, true);
            app.CheckBox_14.Text = '';
            app.CheckBox_15 = uicheckbox(app.Panel_5);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBox_15ValueChanged, true);
            app.CheckBox_15.Text = '';
            app.CTCTGGLabel = uilabel(app.Panel_5);
            app.CTCTGGLabel.Text = 'C T C T G G';
            app.Label_24 = uilabel(app.Panel_5);
            app.Label_24.Text = '1.';
            app.Label_25 = uilabel(app.Panel_5);
            app.Label_25.Text = '2.';
            app.Label_26 = uilabel(app.Panel_5);
            app.Label_26.Text = '3.';
            app.Label_27 = uilabel(app.Panel_5);
            app.Label_27.Text = '4.';
            app.Label_28 = uilabel(app.Panel_5);
            app.Label_28.Text = '5.';
            app.Label_29 = uilabel(app.Panel_5);
            app.Label_29.Text = '6.';
            app.Label_30 = uilabel(app.Panel_5);
            app.Label_30.Text = '→';
            app.Label_31 = uilabel(app.Panel_5);
            app.Label_31.Text = '→';
            app.Label_32 = uilabel(app.Panel_5);
            app.Label_32.Text = '→';
            app.Label_33 = uilabel(app.Panel_5);
            app.Label_33.Text = '←';
            app.Label_34 = uilabel(app.Panel_5);
            app.Label_34.Text = '←';
            app.Label_35 = uilabel(app.Panel_5);
            app.Label_35.Text = '←';
            app.Label_36 = uilabel(app.Panel_5);
            app.Label_36.Text = '5''                          3''  ';
            app.Label_37 = uilabel(app.Panel_5);
            app.Label_37.Text = '3''                          5''  ';
            app.ILabel = uilabel(app.Panel_5);
            app.ILabel.Text = 'I';
            app.ILabel_2 = uilabel(app.Panel_5);
            app.ILabel_2.Text = 'I';
            app.ILabel_3 = uilabel(app.Panel_5);
            app.ILabel_3.Text = 'I';
            app.ILabel_4 = uilabel(app.Panel_5);
            app.ILabel_4.Text = 'I';
            app.ILabel_5 = uilabel(app.Panel_5);
            app.ILabel_5.Text = 'I';
            app.ILabel_6 = uilabel(app.Panel_5);
            app.ILabel_6.Text = 'I';
            app.ILabel_7 = uilabel(app.Panel_5);
            app.ILabel_7.Text = 'I';
            app.ILabel_8 = uilabel(app.Panel_5);
            app.ILabel_8.Text = 'I';
            app.ILabel_9 = uilabel(app.Panel_5);
            app.ILabel_9.Text = 'I';
            app.ILabel_10 = uilabel(app.Panel_5);
            app.ILabel_10.Text = 'I';
            app.ILabel_11 = uilabel(app.Panel_5);
            app.ILabel_11.Text = 'I';
            app.ILabel_12 = uilabel(app.Panel_5);
            app.ILabel_12.Text = 'I';
            app.ILabel_13 = uilabel(app.Panel_5);
            app.ILabel_13.Text = 'I';
            app.ILabel_14 = uilabel(app.Panel_5);
            app.ILabel_14.Text = 'I';
            app.ILabel_15 = uilabel(app.Panel_5);
            app.ILabel_15.Text = 'I';
            app.ILabel_16 = uilabel(app.Panel_5);
            app.ILabel_16.Text = 'I';
            app.ILabel_17 = uilabel(app.Panel_5);
            app.ILabel_17.Text = 'I';
            app.ILabel_18 = uilabel(app.Panel_5);
            app.ILabel_18.Text = 'I';
            app.Button_4 = uibutton(app.Panel_3, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '?';
            app.SomesilentmutationsLabel_2 = uilabel(app.Panel_3);
            app.SomesilentmutationsLabel_2.Text = 'Some silent mutations';
            app.arenotavailablebecauseLabel_2 = uilabel(app.Panel_3);
            app.arenotavailablebecauseLabel_2.Text = 'are not available because';
            app.themutationsshouldbeatLabel = uilabel(app.Panel_3);
            app.themutationsshouldbeatLabel.Text = 'the mutations should be at';
            app.least5ntawayfromthe3Label = uilabel(app.Panel_3);
            app.least5ntawayfromthe3Label.Text = 'least 5 nt away from the 3''';
            app.endpleaseselectanotherLabel = uilabel(app.Panel_3);
            app.endpleaseselectanotherLabel.Text = 'end, please select another ';
            app.mutationorextendtheLabel = uilabel(app.Panel_3);
            app.mutationorextendtheLabel.Text = 'mutation or extend the';
            app.homologoussequenceLabel = uilabel(app.Panel_3);
            app.homologoussequenceLabel.Text = 'homologous sequence !';
        end
    end

    methods (Access = public)

        % Construct app
        function app = oligoprimeron_new

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COSPLAY)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COSPLAY)
        end
    end
end